classdef ADCP_Importa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportaDatpsADCPUIFigure       matlab.ui.Figure
        MarcaDropDownLabel             matlab.ui.control.Label
        Marca                          matlab.ui.control.DropDown
        ArchivoEditFieldLabel          matlab.ui.control.Label
        Archivo                        matlab.ui.control.EditField
        Mensajes                       matlab.ui.control.ListBox
        MetadataPanel                  matlab.ui.container.Panel
        CoordenadasDropDownLabel       matlab.ui.control.Label
        Coordenadas                    matlab.ui.control.DropDown
        ProyectoEditFieldLabel         matlab.ui.control.Label
        Proyecto                       matlab.ui.control.EditField
        SectorEditFieldLabel           matlab.ui.control.Label
        Sector                         matlab.ui.control.EditField
        ReferenciaEditFieldLabel       matlab.ui.control.Label
        ReferenciaEditField            matlab.ui.control.EditField
        InstaladoDropDownLabel         matlab.ui.control.Label
        InstaladoDropDown              matlab.ui.control.DropDown
        LongitudEditFieldLabel         matlab.ui.control.Label
        Longitud                       matlab.ui.control.EditField
        LatitudEditFieldLabel          matlab.ui.control.Label
        Latitud                        matlab.ui.control.EditField
        CoordenadaEsteEditFieldLabel   matlab.ui.control.Label
        CoordenadaEste                 matlab.ui.control.NumericEditField
        CoordenadaNorteEditFieldLabel  matlab.ui.control.Label
        CoordenadaNorte                matlab.ui.control.NumericEditField
        ZonaDropDownLabel              matlab.ui.control.Label
        Zona                           matlab.ui.control.DropDown
        NS                             matlab.ui.control.DropDown
        PresionDropDownLabel           matlab.ui.control.Label
        PresionDropDown                matlab.ui.control.DropDown
        ArchivoPresionEditFieldLabel   matlab.ui.control.Label
        ArchivoPresion                 matlab.ui.control.EditField
        Importar                       matlab.ui.control.Button
        Cerrar                         matlab.ui.control.Button
        NetCDFEditFieldLabel           matlab.ui.control.Label
        NetCDF                         matlab.ui.control.EditField
    end

  
  methods (Access = private)
    
    function ensembles = adcprdi(app)
      fid = fopen(app.Archivo.Value, 'rb');
      data = fread(fid, inf, '*uint8');
      fclose(fid);
      headerID        = 127;      % hexadecimal '7F'
      dataSourceID    = 127;      % hexadecimal '7F'
      idh = data(1:end-1) == headerID;
      ids = data(2:end) == dataSourceID;
      idx = find(idh & ids);
      clear idh ids;
      % number of bytes in ensemble (not including the 2 checksum bytes)
      bpe     = [data(idx(:)+2) data(idx(:)+3)]';
      nBytes  = bytecast(bpe(:), 'L', 'uint16','L');
      clear bpe;
      % number of bytes in ensemble
      nLen = nBytes + 2;
      lenData = length(data);
      
      % keep funky eLen values from putting us out of bounds
      oob = (idx+nLen-1) > lenData;
      idx(oob)    = [];
      nBytes(oob) = [];
      nLen(oob)   = [];
      clear oob;
      
      % check sum is in last two bytes
      givenCrc = indexData(app,data, idx+nLen-2, idx+nLen-1, 'uint16')';
      clear nLen;
      
      % could use indexData to recover data between idx and idx+nBytes-1
      % but at this point nBytes could be as big as FFFFh so ...
      % to compute Crc sum over each ensemble
      % !!! double(data) may be too big to be handled with current free memory
      switch computer
        case {'PCWIN','PCWIN64'}
          % Let's consider memory issues
          userview = memory;
          % 10% margin
          bytesAvailable = userview.MaxPossibleArrayBytes - ...
            10*userview.MaxPossibleArrayBytes/100;
          % let's see if cumsum(double(data)) fit
          bytesNeeded = lenData*8 * 2;
          if bytesNeeded < bytesAvailable
            dataSum = uint32(cumsum(double(data)));
          else
            % we have to chop the process in a number of times the current free
            %  memory allow us to, with the hack of casting dataSum in uint32
            n = ceil((bytesNeeded) / bytesAvailable);
            dataSum = [];
            for i=1:n
              iStart = floor((i-1)*lenData/n) + 1;
              iEnd   = floor(i*lenData/n);
              if isempty(dataSum)
                dataSum = uint32(cumsum(double(data(iStart:iEnd))));
              else
                dataSum = [dataSum; uint32(cumsum(double(data(iStart:iEnd))))...
                  +dataSum(end)]; %#ok<AGROW>
              end
            end
          end
        otherwise
          % We suppose we can read the whole file at once without any memory
          % trouble
          dataSum = uint32(cumsum(double(data)));
      end
      % idx is the start of the sum sequence, iend is the end
      iend = idx+nBytes-1;
      % following formula gives total sum between idx and iend
      calcCrc = double(data(idx)) + double(dataSum(iend) - dataSum(idx));
      clear dataSum;
      % this is the checksum formula
      calcCrc = bitand(calcCrc, 65535);
      % only good ensembles should pass the checksum test
      good = (calcCrc == givenCrc);
      % define ensemble variables
      idx     = idx(good);
      nBytes  = nBytes(good);
      % checksum should have been sufficent
      % but I have still found ensembles that don't
      % have matching bytes (probably due to sheer number of ensembles there is a
      % finite chance of an erroneous checksum match)
      %
      % Difference in index should be equal to nBytes or
      % we have a problem.
      % calculate difference in index-2 from start to end of data
      didx = [diff(idx); lenData - idx(end) + 1] - 2;
      % compare with number of Bytes to find errors
      idodgy = (didx ~= nBytes);
      if any(idodgy)
        % wow! we need to keep trying to fix;
        % at this point I am assuming that nBytes is the same for all good records,
        % since this information is stored in every ensemble this may eventually
        % break down for unseen formats.
        if std(nBytes(~idodgy)) == 0
          % we only have one byte length in data record
          nBytes0 = nBytes(~idodgy);
          igood = (nBytes == nBytes0(1));
          idx     = idx(igood);
          nBytes  = nBytes(igood); 
        else
          error(['this is interesting, need ' ...
            'to look at this data file please contact developer']);
        end
      end
      % in principle each ensemble could have a different number of data
      % types
      nDataTypes = double(data(idx+5));
      % in each ensemble bytes 6:2*nDataTypes give offsets to data
      dataOffsets = indexData(app,data, idx+6, idx+6+2*nDataTypes-1, 'uint16');
      [i,~] = size(dataOffsets);
      % create a big index matrix!
      IDX = meshgrid(idx, 1:i) + dataOffsets;
      % get rid of possible NaN
      iNaN = isnan(IDX);
      IDXnoNan = IDX(~iNaN);
      % what type of sections do we have in each ensemble;
      sType = indexData(app,data, IDXnoNan(:), IDXnoNan(:)+1, 'uint16');
      iFixedLeader    = IDX(sType == 0);
      iVarLeader      = IDX(sType == 128);
      iVel            = IDX(sType == 256);
      iCorr           = IDX(sType == 512);
      iEcho           = IDX(sType == 768);
      iPCgood         = IDX(sType == 1024);
      %iStatProf      = IDX(sType == 1280);
      iBTrack         = IDX(sType == 1536);
      %iMicroCat      = IDX(sType == 2048);
      % major change to PM code - ensembles is a scalar structure not cell array
      ensembles.fixedLeader       = parseFixedLeader(app,data, iFixedLeader);
      ensembles.variableLeader    = parseVariableLeader(app,data, iVarLeader);
      % subfields contain the vector time series
      nCells = ensembles.fixedLeader.numCells;
      ensembles.velocity          = parseVelocity(app,data, nCells, iVel);
      if ~isempty(iCorr)
        ensembles.corrMag       = parseX(app,data, nCells, 'corrMag', iCorr);
      end
      if ~isempty(iEcho)
        ensembles.echoIntensity = parseX(app,data, nCells, 'echoIntensity', iEcho);
      end
      if ~isempty(iPCgood)
        ensembles.percentGood   = parseX(app,data, nCells, 'percentGood', iPCgood);
      end
      if ~isempty(iBTrack)
        ensembles.bottomTrack   = parseBottomTrack(app,data, iBTrack);
      end
    end
    
    function dsub = indexData(~,data, istart, iend, dtype)
      % function dsub = indexData(data, istart, iend, dtype)
      % vectorized data index algorithm, converts data between istart and iend
      % from binary 8bit data to dtype, where istart and iend can be vectors!
      % Charles James
      
      % create matrix of indicies
      width = max(iend-istart+1);
      
      [I,J]   = meshgrid(istart, 0:width-1);
      K       = meshgrid(iend,   0:width-1);
      
      IND = I + J;
      
      ibad = IND > K;
      
      if any(any(ibad))
        % We assume that if an ensemble contain one bad data, all data are bad
        % in this ensemble
        ibad(:,any(ibad)) = ones(size(ibad,1), 1);
      end
      
      datatst = data(IND(:));
      datatst(ibad) = 0;
      
      dsub = bytecast(datatst, 'L', dtype,'L');
      ibad = bytecast(uint8(ibad(:)), 'L', dtype,'L');
      
      i = length(istart);
      j = length(dsub)/i;
      
      dsub = reshape(dsub, j, i);
      ibad = reshape(ibad, j, i) ~= 0;
      
      dsub(ibad) = nan;
      
    end
    
    function [sect, len] = parseFixedLeader(app,data, idx )
      %PARSEFIXEDLEADER Parses a fixed leader section from an ADCP ensemble.
      %
      % Inputs:
      %   data - vector of raw bytes.
      %   idx  - index that the section starts at.
      %
      % Outputs:
      %   sect - struct containing the fields that were parsed from the fixed
      %          leader section.
      %   len  - number of bytes that were parsed.
      %
      
      % assume that fixed means fixed and only store fixed data from first frame!
      idx=idx(1);
      
      sect = struct;
      len = 59;
      
      sect.fixedLeaderId       = indexData(app,data, idx, idx+1, 'uint16')';
      sect.cpuFirmwareVersion  = double(data(idx+2));
      sect.cpuFirmwareRevision = double(data(idx+3));
      %extra
      b=dec2base(data(idx+4),2,8);
      freqs=[75 150 300 600 1200 2400];
      junk=bin2dec(b(6:8));
      sect.frecuencia=freqs(junk+1);
      switch b(5)
        case '0', sect.BeamTipo='Concave Beam';
        case '1', sect.BeamTipo='Convex Beam';
      end
      switch b(2)
        case'0', sect.TrasnHead='Transducer head not attached';
        case '1', sect.TrasnHead='Transducer head attached';
      end
      switch b(1)
        case '0', sect.Orientacion='Down';
        case '1', sect.Orientacion='Up';
      end
      %
      sect.systemConfiguration = indexData(app,data, idx+4, idx+5, 'uint16')';
      sect.realSimFlag         = double(data(idx+6));
      sect.lagLength           = double(data(idx+7));
      sect.numBeams            = double(data(idx+8));
      sect.numCells            = double(data(idx+9));
      block                    = indexData(app,data, idx+10, idx+15, 'uint16')';
      sect.pingsPerEnsemble    = block(:,1);
      sect.depthCellLength     = block(:,2);
      sect.blankAfterTransmit  = block(:,3);
      sect.profilingMode       = double(data(idx+16));
      sect.lowCorrThresh       = double(data(idx+17));
      sect.numCodeReps         = double(data(idx+18));
      sect.gdMinimum           = double(data(idx+19));
      sect.errVelocityMax      = indexData(app,data, idx+20, idx+21, 'uint16')';
      sect.tppMinutes          = double(data(idx+22));
      sect.tppSeconds          = double(data(idx+23));
      sect.tppHundredths       = double(data(idx+24));
      sect.coordinateTransform = double(data(idx+25));
      block                    = indexData(app,data, idx+26, idx+29, 'int16')';
      sect.headingAlignment    = block(:,1);
      sect.headingBias         = block(:,2);
      sect.sensorSource        = double(data(idx+30));
      sect.sensorsAvailable    = double(data(idx+31));
      block                    = indexData(app,data, idx+32, idx+35, 'uint16')';
      sect.bin1Distance        = block(:,1);
      sect.xmitPulseLength     = block(:,2);
      sect.wpRefLayerAvgStart  = double(data(idx+36));
      sect.wpRefLayerAvgEnd    = double(data(idx+37));
      sect.falseTargetThresh   = double(data(idx+38));
      % byte 40 is spare
      sect.transmitLagDistance = indexData(app,data, idx+40, idx+41, 'uint16')';
      sect.cpuBoardSerialNo    = indexData(app,data, idx+42, idx+49, 'uint64')';
      sect.systemBandwidth     = indexData(app,data, idx+50, idx+51, 'uint16')';
      sect.systemPower         = double(data(idx+52));
      % byte 54 is spare
      % following two fields are not used for Firmware before 16.30
      if (sect.cpuFirmwareVersion >= 16) && (sect.cpuFirmwareRevision >= 30)
        sect.instSerialNumber    = num2str(uint32(bytecast(...
          data(idx+54:idx+57), 'L', 'uint32','L')));
        sect.beamAngle           = double(data(idx+58));
      else
        sect.instSerialNumber    = '';
        sect.beamAngle           = NaN;
      end
    end
    
    function [sect,len] = parseVariableLeader(app, data, idx )
      %PARSEVARIABLELEADER Parses a variable leader section from an ADCP ensemble.
      %
      % Inputs:
      %   data - vector of raw bytes.
      %   idx  - index that the section starts at.
      %
      % Outputs:
      %   sect - struct containing the fields that were parsed from the
      %          variable leader section.
      %   len  - number of bytes that were parsed.
      %
      sect = struct;
      len = 65;
      
      block                       = indexData(app,data,idx,idx+3, 'uint16')';
      sect.variableLeaderId       = block(:,1);
      sect.ensembleNumber         = block(:,2);
      sect.rtcYear                = double(data(idx+4));
      sect.rtcMonth               = double(data(idx+5));
      sect.rtcDay                 = double(data(idx+6));
      sect.rtcHour                = double(data(idx+7));
      sect.rtcMinute              = double(data(idx+8));
      sect.rtcSecond              = double(data(idx+9));
      sect.rtcHundredths          = double(data(idx+10));
      sect.ensembleMsb            = double(data(idx+11));
      block                       = indexData(app,data,idx+12,idx+19, 'uint16')';
      sect.bitResult              = block(:,1);
      sect.speedOfSound           = block(:,2);
      sect.depthOfTransducer      = block(:,3);
      sect.heading                = block(:,4);
      block                       = indexData(app,data,idx+20,idx+23, 'int16')';
      sect.pitch                  = block(:,1);
      sect.roll                   = block(:,2);
      sect.salinity               = indexData(app,data,idx+24,idx+25, 'uint16')';
      sect.temperature            = indexData(app,data,idx+26,idx+27, 'int16')';
      sect.mptMinutes             = double(data(idx+28));
      sect.mptSeconds             = double(data(idx+29));
      sect.mptHundredths          = double(data(idx+30));
      sect.hdgStdDev              = double(data(idx+31));
      sect.pitchStdDev            = double(data(idx+32));
      sect.rollStdDev             = double(data(idx+33));
      sect.adcChannel0            = double(data(idx+34));
      sect.adcChannel1            = double(data(idx+35));
      sect.adcChannel2            = double(data(idx+36));
      sect.adcChannel3            = double(data(idx+37));
      sect.adcChannel4            = double(data(idx+38));
      sect.adcChannel5            = double(data(idx+39));
      sect.adcChannel6            = double(data(idx+40));
      sect.adcChannel7            = double(data(idx+41));
      sect.errorStatusWord        = indexData(app,data,idx+42,idx+45, 'uint32')';
      % bytes 47-48 are spare
      % note pressure is technically supposed to be an unsigned integer so when
      % at surface negative values appear huge ~4 million dbar we'll read it in
      % as signed integer to avoid this but need to be careful if deploying
      % ADCP near centre of earth!
      sect.pressure               = indexData(app,data,idx+48,idx+51, 'int32')';
      sect.pressureSensorVariance = indexData(app,data,idx+52,idx+55, 'int32')';
      % byte 57 is spare
      sect.y2kCentury             = double(data(idx+57));
      sect.y2kYear                = double(data(idx+58));
      sect.y2kMonth               = double(data(idx+59));
      sect.y2kDay                 = double(data(idx+60));
      sect.y2kHour                = double(data(idx+61));
      sect.y2kMinute              = double(data(idx+62));
      sect.y2kSecond              = double(data(idx+63));
      sect.y2kHundredth           = double(data(idx+64));
    end
    
    function [sect,len] = parseVelocity(app,data, numCells, idx )
      %PARSEVELOCITY Parses a velocity section from an ADCP ensemble.
      %
      % Inputs:
      %   data     - vector of raw bytes.
      %   numCells - number of depth cells/bins.
      %   idx      - index that the section starts at.
      %
      % Outputs:
      %   sect     - struct containing the fields that were parsed from the
      %              velocity section.
      %   len      - number of bytes that were parsed.
      %
      sect = struct;
      nBeams = 4;
      len = 2 + numCells * nBeams * 2; % 2 bytes per beam
      
      sect.velocityId = indexData(app,data,idx,idx+1, 'uint16')';
      
      idx = idx + 2;
      
      vels = indexData(app,data, idx, idx + 2*nBeams*numCells'-1, 'int16')';
      s = size(vels);
      
      ibeam = 1:nBeams:s(2);
      
      sect.velocity1 = vels(:, ibeam);
      sect.velocity2 = vels(:, ibeam+1);
      sect.velocity3 = vels(:, ibeam+2);
      sect.velocity4 = vels(:, ibeam+3);
      
    end
    
    function [sect,len] = parseX(app,data, numCells, name, idx )
      %PARSEX Parses one of the correlation magnitude, echo intensity or percent
      % good sections from an ADCP ensemble. They all have the same format.
      %
      % Inputs:
      %   data     - vector of raw bytes.
      %   numCells - number of depth cells/bins.
      %   name     - what section is being parsed, e.g. 'correlationMagnitude',
      %              'echoIntensity' or 'percentGood'. This is used as a prefix
      %              for the ID field.
      %   idx      - index that the section starts at.
      %
      % Outputs:
      %   sect     - struct containing the fields that were parsed from the given
      %              section.
      %   len      - number of bytes that were parsed.
      %
      sect = struct;
      nBeams = 4;
      len = 2 + numCells * nBeams;
      
      sect.([name 'Id']) = indexData(app,data,idx,idx+1,'uint16');
      
      idx = idx + 2;
      
      fields = indexData(app,data,idx,idx + nBeams*numCells'-1, 'uint8')';
      s = size(fields);
      ibeam = 1:nBeams:s(2);
      
      sect.field1 = fields(:, ibeam);
      sect.field2 = fields(:, ibeam+1);
      sect.field3 = fields(:, ibeam+2);
      sect.field4 = fields(:, ibeam+3);
      
    end
    
    function [sect,length] = parseBottomTrack(app,data, idx )
      %PARSEBOTTOMTRACK Parses a bottom track data section from an ADCP
      % ensemble.
      %
      % Inputs:
      %   data   - vector of raw bytes.
      %   idx    - index that the section starts at.
      %
      % Outputs:
      %   sect   - struct containing the fields that were parsed from trawhe bottom
      %            track section.
      %   length - number of bytes that were parsed.
      %
      sect = struct;
      length = 85;
      
      block                       = indexData(app,data, idx, idx+5, 'uint16')';
      sect.bottomTrackId          = block(:,1);
      sect.btPingsPerEnsemble     = block(:,2);
      sect.btDelayBeforeReacquire = block(:,3);
      sect.btCorrMagMin           = double(data(idx+6));
      sect.btEvalAmpMin           = double(data(idx+7));
      sect.btPercentGoodMin       = double(data(idx+8));
      sect.btMode                 = double(data(idx+9));
      sect.btErrVelMax            = indexData(app,data, idx+10, idx+11, 'uint16')';
      % bytes 13-16 are spare
      block                       = indexData(app,data, idx+16, idx+31, 'uint16')';
      sect.btBeam1Range           = block(:,1);
      sect.btBeam2Range           = block(:,2);
      sect.btBeam3Range           = block(:,3);
      sect.btBeam4Range           = block(:,4);
      sect.btBeam1Vel             = block(:,5);
      sect.btBeam2Vel             = block(:,6);
      sect.btBeam3Vel             = block(:,7);
      sect.btBeam4Vel             = block(:,8);
      sect.btBeam1Corr            = double(data(idx+32));
      sect.btBeam2Corr            = double(data(idx+33));
      sect.btBeam3Corr            = double(data(idx+34));
      sect.btBeam4Corr            = double(data(idx+35));
      sect.btBeam1EvalAmp         = double(data(idx+36));
      sect.btBeam2EvalAmp         = double(data(idx+37));
      sect.btBeam3EvalAmp         = double(data(idx+38));
      sect.btBeam4EvalAmp         = double(data(idx+39));
      sect.btBeam1PercentGood     = double(data(idx+40));
      sect.btBeam2PercentGood     = double(data(idx+41));
      sect.btBeam3PercentGood     = double(data(idx+42));
      sect.btBeam4PercentGood     = double(data(idx+43));
      block                       = indexData(app,data, idx+44, idx+49, 'uint16')';
      sect.btRefLayerMin          = block(:,1);
      sect.btRefLayerNear         = block(:,2);
      sect.btRefLayerFar          = block(:,3);
      block                       = indexData(app,data, idx+50, idx+57, 'int16')';
      sect.btBeam1RefLayerVel     = block(:,1);
      sect.btBeam2RefLayerVel     = block(:,2);
      sect.btBeam3RefLayerVel     = block(:,3);
      sect.btBeam4RefLayerVel     = block(:,4);
      sect.btBeam1RefCorr         = double(data(idx+58));
      sect.btBeam2RefCorr         = double(data(idx+59));
      sect.btBeam3RefCorr         = double(data(idx+60));
      sect.btBeam4RefCorr         = double(data(idx+61));
      sect.btBeam1RefInt          = double(data(idx+62));
      sect.btBeam2RefInt          = double(data(idx+63));
      sect.btBeam3RefInt          = double(data(idx+64));
      sect.btBeam4RefInt          = double(data(idx+65));
      sect.btBeam1RefGood         = double(data(idx+66));
      sect.btBeam2RefGood         = double(data(idx+67));
      sect.btBeam3RefGood         = double(data(idx+68));
      sect.btBeam4RefGood         = double(data(idx+69));
      sect.btMaxDepth             = indexData(app,data, idx+70, idx+71, 'uint16')';
      sect.btBeam1RssiAmp         = double(data(idx+72));
      sect.btBeam2RssiAmp         = double(data(idx+73));
      sect.btBeam3RssiAmp         = double(data(idx+74));
      sect.btBeam4RssiAmp         = double(data(idx+75));
      sect.btGain                 = double(data(idx+76));
      sect.btBeam1RangeMsb        = double(data(idx+77));
      sect.btBeam2RangeMsb        = double(data(idx+78));
      sect.btBeam3RangeMsb        = double(data(idx+79));
      sect.btBeam4RangeMsb        = double(data(idx+80));
      %bytes 82-85 are spare
    end
    
    function V = adcpsontek(app)
      % adp.m: a general function for extracting variables from Sontek
      %        adp data files.  This version is specific to the 4-beam "type 2"
      %        files, but could easily be modified for "type 1" or others.
      % Usage:
      %    [ts, ts2, v, s, a, n, dd] = adp(fileroot, 'ts', 'ts2', ...
      %                    'vel', 'std', 'amp', 'noise', step, range);
      %    Inputs:
      %        fileroot: name of adp data file, with or without
      %              extension ('.adp' or '.ADP')
      %        'ts' ... 'amp' is a list of the desired variables.
      %              The output arrays will be in the order of these input
      %              strings, and only the ones listed will be output.
      %        step: optional scalar for subsampling; 1 (default) means take
      %              every sample, 2 means take every other one, etc.
      %        range: optional 2-element vector specifying the first and last
      %              profile in the file to be extracted.  The profiles
      %              taken are range(1):step:range(2); default range is
      %              entire file.
      %     Dimensions:
      %        NP: number of profiles extracted
      %        NC: number of depth cells
      %        NB: number of beams (hardwired here to 4!)
      %     Outputs:
      %        (dd is always available; the others are available only if
      %        requested via the input arguments.)
      %        ts: NPx5 array.  Columns are heading, pitch, roll, temperature,
      %              and pressure.  Units are degrees, degrees C, and decibars.
      %        ts2: NPx5 array.  Columns are Xflux, Yflux, Zflux,
      %              compass temperature, and voltage.  Units are
      %              microteslas, degrees C, and Volts.
      %        v: NCxNBxNP array of velocities in m/s
      %        s: NCxNBxNP array of velocity standard deviations in m/s
      %        a: NCxNBxNP array of signal/noise in db (counts * 0.43)
      %              based on the noise level given in the header.
      %              If "rawamp" is requested, then it is just raw counts.
      %        n: 1xNB array of noise levels from header.
      %        dd: NPx1 time of each profile in decimal days since the
      %              start of the year; noon on January 1 is 0.5, NOT 1.5.
      %     Note: the squeeze function has to be used to plot slices of
      %     these 3-D arrays.  For example, to plot the time series of
      %     velocity in beam 1, cells 2 and 6, use:
      %         plot(dd, squeeze(v([2 6],1,:)).')
      %
      %     Sun  98/03/22 Eric Firing
      %     Mon  98/04/27 EF added ts2 array.
      %     2000/03/17 EF allow input filename with extension
      %     2000/04/19 EF add noise output
      
      % The following are hardwired here, but could be read from
      % the file.
      
      nbeams = 3;
      fileheadlength = 416;
      profheadlength = 80;
      
      % Defaults:
      step = 1;
      profs = 0;
      %raw = 1;    % Default: snr rather than raw amplitude.
      
      out.ts = 1;
      out.ts2 = 1;
      out.vel = 1;
      out.amp = 1;
      out.std = 1;
      out.noise = 1;
      
      % Read the input arguments:
      fileroot = app.Archivo.Value;
      nout = 0;
      nout = nout + 1; out.ts = nout;
      nout = nout + 1; out.ts2 = nout;
      nout = nout + 1; out.vel = nout;
      nout = nout + 1; out.amp = nout;
      nout = nout + 1; out.amp = nout; raw = 1;
      nout = nout + 1; out.noise = nout;
      nout = nout + 1; out.std = nout;
      
      if exist(fileroot,'file')
        filename = fileroot;
      else
        filename = [fileroot, '.adp'];
        if exist(filename,'file')
          filename = [fileroot, '.ADP'];
        end
      end
      fid = fopen(filename, 'r', 'l'); % Little-endian data file.
      if isequal(fid, -1)
        error(['Can''t open input file: ', fileroot, ', with or without .adp or .ADP']);
      end
      
      % Read the pressure calibration factors:
      fseek(fid, 70, 'bof');
      p_params = fread(fid, 2, 'int32');
      pscale = 1e-8*p_params(1);
      poffset = 1e-5*p_params(2);
      fseek(fid, 82, 'bof');
      p_params = fread(fid, 1, 'int16');
      pscale2 = 1e-12*p_params;
      
      % Read the number of cells:
      fseek(fid, 178, 'bof');
      ncells = fread(fid, 1, 'int16');
      
      proflength = profheadlength + 4*nbeams*ncells + 2;
      % 2 for vel, 1 each std, amp
      % The extra two bytes are the checksum.
      
      % How many profiles are in the file?
      fseek(fid, 0, 'eof');
      file_nbytes = ftell(fid);
      nprofiles = fix((file_nbytes - fileheadlength)/proflength);
      
      if size(profs(:),1) ~= 2
        irange = 1:step:nprofiles;
      else
        irange =  max([1 profs(1)]):step:min([nprofiles profs(2)]);
      end
      
      np = length(irange);  % Number of profiles to be extracted.
      tt = NaN + zeros(np,6);
      
      ofs.time  = 20;
      ofs.noise = 68;
      ofs.ts    = 40;
      ofs.ts2   = 60;
      ofs.vel   = profheadlength;
      ofs.amp   = profheadlength + 3*nbeams*ncells;
      ofs.std   = profheadlength + 2*nbeams*ncells;
      
      if out.ts,   A = zeros(np,5);               end
      if out.ts2,  B = zeros(np,5);               end
      if out.ts2,  B1 = zeros(np,2);              end
      if out.ts2,  B2 = zeros(np,1);              end
      if out.ts2,  B3 = zeros(np,2);              end
      if out.vel,  v = zeros(ncells, nbeams, np); end
      if out.amp,  a = zeros(ncells, nbeams, np); end
      if out.std,  s = zeros(ncells, nbeams, np); end
      
      cs_offset = hex2dec('a596');
      bad_checksum = 0;
      iout = 1;
      
      for ii = irange
        p_offset = fileheadlength+(ii-1)*proflength;
        
        [~] = fseek(fid, p_offset, 'bof');
        buf = fread(fid, proflength-2, 'uint8');
        checksum = fread(fid, 1, 'uint16');
        cs = rem(sum(buf) + cs_offset, 65536);
        if checksum ~= cs
          bad_checksum = 1;
          break
        end
        
        if iout == 1
          [~] = fseek(fid, p_offset + ofs.time - 2, 'bof');
          year = fread(fid, 1, 'int16');
        end
        
        [~] = fseek(fid, p_offset + ofs.time, 'bof');
        tt(iout,:) = fread(fid, [1,6], 'int8');
        
        if out.ts
          [~] = fseek(fid, p_offset + ofs.ts, 'bof');
          A(iout,:) = fread(fid, [1,5], 'int16');
        end
        
        if out.ts2
          [~] = fseek(fid, p_offset + ofs.ts2, 'bof');
          B1(iout,:) = fread(fid, [1,2], 'int16');
          [~] = fseek(fid, 11, 'cof');
          B2(iout,:) = fread(fid, [1,1], 'int8');
          B3(iout,:) = fread(fid, [1,2], 'int16');
        end
        
        if out.vel
          [~] = fseek(fid, p_offset + ofs.vel, 'bof');
          v(:,:,iout) = fread(fid, [ncells,nbeams], 'int16') * 1e-3;
        end
        
        if out.std
          [~] = fseek(fid, p_offset + ofs.std, 'bof');
          ss = fread(fid, [ncells,nbeams], 'int16');
          ineg = find(ss<0);
          ss(ineg) = ss(ineg) + 256.0;
          s(:,:,iout) = ss * 1e-3;
        end
        
        if iout == 1 && (out.noise ~= 0 || raw == 0)
          [~] = fseek(fid, p_offset + ofs.noise, 'bof');
          noise = fread(fid, [1,4], 'uint8');
          N = noise(ones(ncells,1),:);
        end
        
        if out.amp
          [~] = fseek(fid, p_offset + ofs.amp, 'bof');
          amp = fread(fid, [ncells,nbeams], 'uint8');
          if raw == 0
            a(:,:,iout) = 0.43 * (amp - N);
          else
            a(:,:,iout) = amp;
          end
        end
        
        iout = iout+1;
      end
      fclose(fid);
      
      if bad_checksum
        imiss = ii:np;
        tt(imiss,:) = [];
        if out.ts,   A(imiss, :) = [];   end
        if out.ts2,  B(imiss, :) = [];   end
        if out.ts2,  B(imiss, :) = [];   end
        if out.ts2,  B2(imiss, :) = [];  end
        if out.ts2,  B3(imiss, :) = [];  end
        if out.vel,  v(:, :, imiss) = []; end
        if out.amp,  a(:, :, imiss) = []; end
        if out.std,  s(:, :, imiss) = []; end
      end
      
      if out.ts
        iwrap = find(A(:,5) < 0);
        if ~isempty(iwrap), A(iwrap,5) = A(iwrap,5) + 65536; end
        A(:,5) = poffset + pscale * A(:,5) + pscale2 * A(:,5).^2;
        A(:,1:3) = 0.1*A(:,1:3);
        A(:,4) = 0.01*A(:,4);
      end
      
      if out.ts2
        B(:,1:2) = B1 * 0.01;               % Xflux, Yflux; microteslas
        B(:,3)   = B3(:,1) * 0.01;          % Zflux
        B(:,4)   = B3(:,2) * 0.1;          % CompassTemp
        B(:,5)   = B2 * 0.2;               % Voltage
      end
      
      tn = datenum(year,tt(:,2),tt(:,1),tt(:,4),tt(:,3),tt(:,6)+tt(:,5)/100);
      dday = tn - datenum(year,1,1,0,0,0);
      
      V.day = dday;
      V.A=A;
      V.B=B;
      V.v = v;
      V.s = s;
      V.a = a;
      V.noise = noise;
    end
    
    function V = adcpsontek_ctl(app)
      fid=fopen([archivo '.ctl']);
      while ~feof(fid)
        linea=fgetl(fid);
        if contains(linea,'ArgType')
          jj=find(linea=='-');
          V.modelo=linea(jj(end)+1:end);
        end
        if contains(linea,'Frequency')
          jj=find(linea=='-');
          V.Frecuencia=linea(jj(end)+1:end);
        end
        if contains(linea,'BlankDistance')
          jj=find(linea=='-');
          V.blank=str2double(linea(jj(end)+1:end));
        end
        if contains(linea,'CellSize')
          jj=find(linea=='-');
          V.c_size=str2double(linea(jj(end)+1:end));
        end
        if contains(linea,'Number of Cells')
          jj=find(linea=='-');
          n_capas=str2double(linea(jj(end)+1:end));
          V.n_capas=n_capas;
        end
      end
      fclose(fid);
      V.capa=NaN(n_capas,1);
      for j=1:n_capas
        V.capa(j)=V.blank+V.c_size*(0.5+(j-1));
      end
      %
      % Lectura de las componentes de la velocidad de cada capa
      % los datos se almacenan en variables con nombre en minuscula
      %
      fid=fopen([archivo '.vel']);
      %
      % Determinacion de numero de registros a leer
      %
      k=0;
      while ~feof(fid)
        linea=fgets(fid); %#ok<*NASGU>
        k=k+1;
      end
      frewind(fid)
      n_reg=k-2;
      
      linea=fgets(fid);
      linea=fgets(fid);
      fecha=NaN(n_reg,1);
      [u,v,w,mag,dire]=deal(NaN(n_reg,n_capas));
      for k=1:n_reg
        linea=fgets(fid);
        C=textscan(linea,'%f');C=C{1};
        fecha(k,1)=datenum(C(2),C(3),C(4),C(5),C(6),C(7));
        for c=1:n_capas
          u(k,c)=C(8+(c-1)*5);
          v(k,c)=C(9+(c-1)*5);
          w(k,c)=C(10+(c-1)*5);
          mag(k,c)=C(11+(c-1)*5);
          dire(k,c)=C(12+(c-1)*5);
        end
      end
      V.fecha=fecha;
      V.u=u;
      V.v=v;
      V.w=w;
      V.dir=dire;
      V.mag=mag;
      fclose(fid);
      clear p c k linea fid fecha u v w dire mag
      %
      % Lectura de la velocidades integradas en la columna de agua y de los datos
      % de heading, pitch, roll, temperatura y presion
      %
      % los nombres de las variables empiezan con Mayuscula
      %
      M = importdata([archivo '.dat'], ' ', 1);
      Fecha=datenum(M.data(:,1:6));
      V.U=M.data(:,7);
      V.V=M.data(:,8);
      V.W=M.data(:,9);
      V.Heading=M.data(:,23);
      V.Pitch=M.data(:,24);
      V.Roll=M.data(:,25);
      V.Temp=M.data(:,29);
      V.Presion=M.data(:,30);
      V.Mag=M.data(:,35);
      V.Direccion=M.data(:,36);
      clear M
      % Lectura del SNR de las componentes de la velocidad de cada capa
      % los datos se almacenan en variables con nombre en minuscula
      %
      fid=fopen([archivo '.snr']);
      %
      % Determinacion de numero de registros a leer
      %
      k=0;
      while ~feof(fid)
        linea=fgets(fid);
        k=k+1;
      end
      frewind(fid)
      n_reg=k-2;
      
      linea=fgets(fid);
      linea=fgets(fid);
      fecha=NaN(n_reg,1);
      [snr1,snr2,snr3,amp1,amp2,amp3]=deal(NaN(n_reg,n_capas));
      nv=6;
      for k=1:n_reg
        linea=fgets(fid);
        fecha(k,1)=datenum(linea(10:35),'yyyy mm dd HH MM SS');
        for c=1:n_capas
          p=29;snr1(k,c)=str2double(linea(p+11*nv*(c-1):+p+n_capas+11*nv*(c-1)));
          p=40;snr2(k,c)=str2double(linea(p+11*nv*(c-1):+p+n_capas+11*nv*(c-1)));
          p=51;snr3(k,c)=str2double(linea(p+11*nv*(c-1):+p+n_capas+11*nv*(c-1)));
          p=62;amp1(k,c)=str2double(linea(p+11*nv*(c-1):+p+n_capas+11*nv*(c-1)));
          p=73;amp2(k,c)=str2double(linea(p+11*nv*(c-1):+p+n_capas+11*nv*(c-1)));
          p=84;amp3(k,c)=str2double(linea(p+11*nv*(c-1):+p+n_capas+11*nv*(c-1)));
        end
      end
      V.snr1=snr1;V.amp1=amp1;
      V.snr2=snr2;V.amp2=amp2;
      V.snr3=snr3;V.amp3=amp3;
      fclose(fid);
      clear p c k linea fid snr* amp*
      
      % Lectura del error de las componentes de la velocidad de cada capa
      % los datos se almacenan en variables con nombre en minuscula
      %
      fid=fopen([archivo '.std']);
      %
      % Determinacion de numero de registros a leer
      %
      k=0;
      while ~feof(fid)
        linea=fgets(fid);
        k=k+1;
      end
      frewind(fid)
      n_reg=k-2;
      
      linea=fgets(fid);
      linea=fgets(fid);
      fecha=NaN(n_reg,1);
      eu=NaN(n_reg,n_capas);ev=eu;ew=ev;
      for k=1:n_reg
        linea=fgets(fid);
        fecha(k,1)=datenum(linea(10:35),'yyyy mm dd HH MM SS');
        for c=1:n_capas
          p=29;eu(k,c)=str2double(linea(p+11*3*(c-1):+p+n_capas+11*3*(c-1)));
          p=40;ev(k,c)=str2double(linea(p+11*3*(c-1):+p+n_capas+11*3*(c-1)));
          p=51;ew(k,c)=str2double(linea(p+11*3*(c-1):+p+n_capas+11*3*(c-1)));
        end
      end
      fclose(fid);
      
    end


    function sample_data = aquadoppProfilerParse(app)
      %AQUADOPPPROFILERPARSE Parses ADCP data from a raw Nortek Aquadopp Profiler
      % binary (.prf) file.
      %
      % Does not yet support HR Aquadopp profilers.
      %
      % Inputs:
      %   filename    - Cell array containing the name of the raw aquadopp profiler
      %                 file to parse.
      %   tMode       - Toolbox data type mode.
      %
      % Outputs:
      %   sample_data - Struct containing sample data.
      %
      % Author: 		Paul McCarthy <paul.mccarthy@csiro.au>
      % Contributor: 	Guillaume Galibert <guillaume.galibert@utas.edu.au>
      %
      
      %
      % Copyright (C) 2017, Australian Ocean Data Network (AODN) and Integrated
      % Marine Observing System (IMOS).
      %
      % This program is free software: you can redistribute it and/or modify
      % it under the terms of the GNU General Public License as published by
      % the Free Software Foundation version 3 of the License.
      %
      % This program is distributed in the hope that it will be useful,
      % but WITHOUT ANY WARRANTY; without even the implied warranty of
      % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
      % GNU General Public License for more details.
      
      % You should have received a copy of the GNU General Public License
      % along with this program.
      % If not, see <https://www.gnu.org/licenses/gpl-3.0.en.html>.
      %
      
      % only one file supported
      filename = app.Archivo.Value;
      
      % read in all of the structures in the raw file
      structures = readParadoppBinary(filename);
      
      % first three sections are header, head and user configuration
      hardware = structures.Id5;
      head     = structures.Id4;
      user     = structures.Id0;
      
      % the rest of the sections are aquadopp profiler velocity data
      if isfield(structures, 'Id42')
        % this is a HR profiler velocity data
        profilerType = 'Id42';
       	if ~strfind(hardware.instrumentType,'HR')
          fprintf('%s\n', ['Warning : ' filename ...
            ' HR PROFILER instrumentType does not match Id42 sector type data']);
       	end
      else
        % this is a plain profiler velocity data
        profilerType = 'Id33';
       	if strfind(hardware.instrumentType,'HR')
          fprintf('%s\n', ['Warning : ' filename ...
            ' AQUADOPP PROFILER instrumentType does not match Id33 sector type data']);
       	end
       	
      end
      
      % still to be implemented
      value = bin2dec(num2str(bitget(user.TimCtrlReg, 7:-1:6)));
      switch value
        case 0
          user.TimCtrlReg_PowerLevel_ = 'HIGH';
        case 1
          user.TimCtrlReg_PowerLevel_ = 'HIGH-';
        case 2
          user.TimCtrlReg_PowerLevel_ = 'LOW+';
        case 3
          user.TimCtrlReg_PowerLevel_ = 'LOW';
      end
      
      velocityProcessed = false;
      if isfield(structures, 'Id106')
        % velocity has been processed
        velocityProcessed = true;
      end
      
      %
      % calculate distance values from metadata. See continentalParse.m
      % inline comments for a brief discussion of this process
      %
      % http://www.nortek-as.com/en/knowledge-center/forum/hr-profilers/736804717
      %
      freq       = head.Frequency; % this is in KHz
      blankDist  = user.T2;        % counts
      cellSize   = user.BinLength; % counts
      ncells     = user.NBins;
      factor     = 0;              % used for conversion
      
      switch freq
        case 400,  factor = 0.1195;
        case 600,  factor = 0.0797;
        case 1000, factor = 0.0478;
        case 2000, factor = 0.0239;
      end
      
      cellSize   = (cellSize / 256) * factor * cos(25 * pi / 180);
      blankDist  = blankDist        * 0.0229 * cos(25 * pi / 180) - cellSize;
      
      % generate distance values
      distance = (blankDist:  ...
        cellSize: ...
        blankDist + (ncells-1) * cellSize)';
      
      %
      velocityScaling = 1;
      if bitget(user(1).Mode, 5) == 1
        velocityScaling = 0.1;
      end
      if strfind(hardware(1).instrumentType, 'HR_PROFILER')
       	sampleRate = double(512 / user(1).T5);
      end
      
      % Note this is actually the distance between the ADCP's transducers and the
      % middle of each cell
      % See http://www.nortek-bv.nl/en/knowledge-center/forum/current-profilers-and-current-meters/579860330
      distance = distance + cellSize;
      
      % retrieve sample data
      time            = [structures.(profilerType)(:).Time]';
      analn1          = [structures.(profilerType)(:).Analn1]';
      battery         = [structures.(profilerType)(:).Battery]';
      soundSpeed      = [structures.(profilerType)(:).Analn2]';
      heading         = [structures.(profilerType)(:).Heading]';
      pitch           = [structures.(profilerType)(:).Pitch]';
      roll            = [structures.(profilerType)(:).Roll]';
      status          = [structures.(profilerType)(:).Status]';
      pressure        = [structures.(profilerType)(:).PressureMSB]'*65536 + [structures.(profilerType)(:).PressureLSW]';
      temperature     = [structures.(profilerType)(:).Temperature]';
      velocity1       = [structures.(profilerType)(:).Vel1]';
      velocity2       = [structures.(profilerType)(:).Vel2]';
      velocity3       = [structures.(profilerType)(:).Vel3]';
      backscatter1    = [structures.(profilerType)(:).Amp1]';
      backscatter2    = [structures.(profilerType)(:).Amp2]';
      backscatter3    = [structures.(profilerType)(:).Amp3]';
      
      if velocityProcessed
        % velocity has been processed
        timeProc = [structures.Id106(:).Time]';
        iCommonTime = ismember(time, timeProc); % timeProc can be shorter than time
        
        velocity1Proc(iCommonTime, :)    = [structures.Id106(:).Vel1]'; % tilt effect corrected velocity
        velocity2Proc(iCommonTime, :)    = [structures.Id106(:).Vel2]';
        velocity3Proc(iCommonTime, :)    = [structures.Id106(:).Vel3]';
        sig2noise1(iCommonTime, :)       = [structures.Id106(:).Snr1]';
        sig2noise2(iCommonTime, :)       = [structures.Id106(:).Snr2]';
        sig2noise3(iCommonTime, :)       = [structures.Id106(:).Snr3]';
        stdDev1(iCommonTime, :)          = [structures.Id106(:).Std1]'; % currently not used
        stdDev2(iCommonTime, :)          = [structures.Id106(:).Std2]';
        stdDev3(iCommonTime, :)          = [structures.Id106(:).Std3]';
        errorCode1(iCommonTime, :)       = [structures.Id106(:).Erc1]'; % error codes for each cell in one beam, values between 0 and 4.
        errorCode2(iCommonTime, :)       = [structures.Id106(:).Erc2]';
        errorCode3(iCommonTime, :)       = [structures.Id106(:).Erc3]';
        speed(iCommonTime, :)            = [structures.Id106(:).speed]';
        direction(iCommonTime, :)        = [structures.Id106(:).direction]';
        verticalDist(iCommonTime, :)     = [structures.Id106(:).verticalDistance]'; % ? no idea what this is, always same values between 6000 and 65534 for each profile.
        profileErrorCode(iCommonTime, :) = [structures.Id106(:).profileErrorCode]'; % error codes for each cell of a velocity profile inferred from the 3 beams. 0=good; otherwise error. See http://www.nortek-as.com/en/knowledge-center/forum/waves/20001875?b_start=0#769595815
        qcFlag(iCommonTime, :)           = [structures.Id106(:).qcFlag]'; % QUARTOD QC result. 0=not eval; 1=bad; 2=questionable; 3=good.
      end
      clear structures;
      
      % battery     / 10.0   (0.1 V    -> V)
      % soundSpeed  / 10.0   (0.1 m/s  -> m/s)
      % heading     / 10.0   (0.1 deg  -> deg)
      % pitch       / 10.0   (0.1 deg  -> deg)
      % roll        / 10.0   (0.1 deg  -> deg)
      % pressure    / 1000.0 (mm       -> m)   assuming equivalence to dbar
      % temperature / 100.0  (0.01 deg -> deg)
      % velocities  / 1000.0 (mm/s     -> m/s) assuming earth coordinates
      battery      = battery      / 10.0;
      soundSpeed   = soundSpeed   / 10.0;
      heading      = heading      / 10.0;
      pitch        = pitch        / 10.0;
      roll         = roll         / 10.0;
      pressure     = pressure     / 1000.0;
      temperature  = temperature  / 100.0;
      velocity1    = velocity1 * velocityScaling / 1000.0;
      velocity2    = velocity2 * velocityScaling / 1000.0;
      velocity3    = velocity3 * velocityScaling / 1000.0;
      
      if velocityProcessed
        % velocity has been processed
        % velocities  / 1000.0 (mm/s     -> m/s) assuming earth coordinates
        % 20*log10(sig2noise)  (counts   -> dB)
        % direction   / 100.0  (0.01 deg  -> deg)
        velocity1     = velocity1Proc * velocityScaling / 1000.0; % we update the velocity
        velocity2     = velocity2Proc * velocityScaling / 1000.0;
        velocity3     = velocity3Proc * velocityScaling / 1000.0;
        sig2noise1(sig2noise1==0) = NaN;
        sig2noise2(sig2noise2==0) = NaN;
        sig2noise3(sig2noise3==0) = NaN;
        sig2noise1    = 20*log10(sig2noise1);
        sig2noise2    = 20*log10(sig2noise2);
        sig2noise3    = 20*log10(sig2noise3);
        stdDev1       = stdDev1 / 1000.0; % same unit as velocity I suppose
        stdDev2       = stdDev2 / 1000.0;
        stdDev3       = stdDev3 / 1000.0;
        speed         = speed / 1000.0; % same unit as velocity I suppose
        direction     = direction / 100.0;
        verticalDist  = verticalDist / 1000.0; % since verticalDist is uint16, max value gives 65m but distance along beams can go up to 170m...???
      end
      
      if strfind(hardware.instrumentType, 'HR')
        instrument_model = 'HR Aquadopp Profiler';
      else
        instrument_model = 'Aquadopp Profiler';
      end
      
      sample_data = struct;
      
      sample_data.toolbox_input_file              = filename;
      sample_data.meta.featureType                = ''; % strictly this dataset cannot be described as timeSeriesProfile since it also includes timeSeries data like TEMP
      sample_data.meta.head                       = head;
      sample_data.meta.hardware                   = hardware;
      sample_data.meta.user                       = user;
      sample_data.meta.binSize                    = cellSize;
      sample_data.meta.instrument_make            = 'Nortek';
      sample_data.meta.instrument_model           = instrument_model;
      sample_data.meta.instrument_serial_no       = hardware.SerialNo;
      sample_data.meta.instrument_firmware        = hardware.FWversion;
      sample_data.meta.instrument_sample_interval = median(diff(time*24*3600));
      sample_data.meta.instrument_average_interval= user.AvgInterval;
      sample_data.meta.beam_angle                 = 25;   % http://www.hydro-international.com/files/productsurvey_v_pdfdocument_19.pdf
      sample_data.meta.beam_to_xyz_transform      = head.TransformationMatrix;
      
      % add dimensions with their data mapped
      adcpOrientations = single(bitget(status, 1, 'uint8'));
      adcpOrientation = mode(adcpOrientations); % hopefully the most frequent value reflects the orientation when deployed
      height = distance;
      if adcpOrientation == 1
        % case of a downward looking ADCP -> negative values
        height = -height;
        distance = -distance;
      end
      iBadOriented = adcpOrientations ~= adcpOrientation; % we'll only keep velocity data collected when ADCP is oriented as expected
      velocity2(iBadOriented, :) = NaN;
      velocity1(iBadOriented, :) = NaN;
      velocity3(iBadOriented, :) = NaN;
      backscatter1(iBadOriented, :) = NaN;
      backscatter2(iBadOriented, :) = NaN;
      backscatter3(iBadOriented, :) = NaN;
      if velocityProcessed
        sig2noise1(iBadOriented, :) = NaN;
        sig2noise2(iBadOriented, :) = NaN;
        sig2noise3(iBadOriented, :) = NaN;
        stdDev1(iBadOriented, :) = NaN;
        stdDev2(iBadOriented, :) = NaN;
        stdDev3(iBadOriented, :) = NaN;
        errorCode1(iBadOriented, :) = NaN;
        errorCode2(iBadOriented, :) = NaN;
        errorCode3(iBadOriented, :) = NaN;
        speed(iBadOriented, :) = NaN;
        direction(iBadOriented, :) = NaN;
        verticalDist(iBadOriented, :) = NaN;
        profileErrorCode(iBadOriented, :) = NaN;
        qcFlag(iBadOriented, :) = NaN;
      end
      dims = {
        'TIME',             time,     ['Time stamp corresponds to the start of the measurement which lasts ' num2str(user.AvgInterval) ' seconds.']; ...
        'DIST_ALONG_BEAMS', distance, ['Values correspond to the distance between the instrument''s transducers and the centre of each cells. ' ...
        'Nortek instrument data is not vertically bin-mapped (no tilt correction applied). Cells are lying parallel to the beams, ' ...
        'at heights above sensor that vary with tilt.']
        };
      clear time distance;
      
      if velocityProcessed
        % we re-arrange dimensions like for RDI ADCPs
        dims(end+1, :) = dims(end, :);
        dims(end-1, :) = {
          'HEIGHT_ABOVE_SENSOR', height(:), ['Values correspond to the distance between the instrument''s transducers and the centre of each cells. ' ...
          'Data has been vertically bin-mapped using Nortek Storm software ''Remove tilt effects'' procedure. Cells have consistant heights above sensor in time.']
          };
      end
      clear height;
      
      nDims = size(dims, 1);
      sample_data.dimensions = cell(nDims, 1);
      for i=1:nDims
        sample_data.dimensions{i}.name         = dims{i, 1};
        sample_data.dimensions{i}.typeCastFunc = str2func(netcdf3ToMatlabType(imosParameters(dims{i, 1}, 'type')));
        sample_data.dimensions{i}.data         = sample_data.dimensions{i}.typeCastFunc(dims{i, 2});
        sample_data.dimensions{i}.comment      = dims{i, 3};
      end
      clear dims;
      
      % add information about the middle of the measurement period
      sample_data.dimensions{1}.seconds_to_middle_of_measurement = user.AvgInterval/2;
      
      % add variables with their dimensions and data mapped.
      if velocityProcessed
        % velocity has been processed
        iDimVel = nDims-1;
        iDimDiag = nDims;
      else
        iDimVel = nDims;
        iDimDiag = nDims;
      end
      switch user.CoordSystem
        case 0 % ENU
          vel2Name = 'VCUR_MAG'; % we assume no correction for magnetic declination has been applied
          vel1Name = 'UCUR_MAG';
          vel3Name = 'WCUR';
          
        case 2 % Beam
          vel2Name = 'VEL2';
          vel1Name = 'VEL1';
          vel3Name = 'VEL3';
          
        otherwise
          error([mfilename ' only supports ENU and Beam coordinate systems']);
      end
      vars = {
        'TIMESERIES',       [],             1;...
        'LATITUDE',         [],             NaN; ...
        'LONGITUDE',        [],             NaN; ...
        'NOMINAL_DEPTH',    [],             NaN; ...
        vel2Name,           [1 iDimVel],    velocity2; ...
        vel1Name,           [1 iDimVel],    velocity1; ...
        vel3Name,           [1 iDimVel],    velocity3; ...
        'ABSIC1',           [1 iDimDiag],   backscatter1; ...
        'ABSIC2',           [1 iDimDiag],   backscatter2; ...
        'ABSIC3',           [1 iDimDiag],   backscatter3; ...
        'TEMP',             1,              temperature; ...
        'PRES_REL',         1,              pressure; ...
        'VOLT',             1,              battery; ...
        'SSPD',             1,              soundSpeed; ...
        'PITCH',            1,              pitch; ...
        'ROLL',             1,              roll; ...
        'HEADING_MAG',      1,              heading
        };
      clear analn1 analn2 time distance velocity1 velocity2 velocity3 ...
        backscatter1 backscatter2 backscatter3 soundSpeed ...
        temperature pressure battery pitch roll heading status;
      
      if velocityProcessed
        % velocity has been processed
        vars = [vars; {
          'SNR1',                [1 iDimDiag], sig2noise1; ...
          'SNR2',                [1 iDimDiag], sig2noise2; ...
          'SNR3',                [1 iDimDiag], sig2noise3; ...
          %         'STDB1',               [1 iDimDiag], stdDev1; ... % currently not used
          %         'STDB2',               [1 iDimDiag], stdDev2; ...
          %         'STDB3',               [1 iDimDiag], stdDev3; ...
          'NORTEK_ERR1',         [1 iDimDiag], errorCode1; ...
          'NORTEK_ERR2',         [1 iDimDiag], errorCode2; ...
          'NORTEK_ERR3',         [1 iDimDiag], errorCode3; ...
          'CSPD',                [1 iDimVel],  speed; ...
          'CDIR_MAG',            [1 iDimVel],  direction; ...
          %         'VERT_DIST',           [1 iDimVel],  verticalDist; ... % don't know what this is
          'NORTEK_PROFILE_ERR',  [1 iDimVel],  profileErrorCode; ...
          'NORTEK_QC',           [1 iDimVel],  qcFlag
          }];
        clear sig2noise1 sig2noise2 sig2noise3 stdDev1 stdDev2 stdDev3 ...
          errorCode1 errorCode2 errorCode3 speed direction verticalDist ...
          profileErrorCode qcFlag;
      end
      
      nVars = size(vars, 1);
      sample_data.variables = cell(nVars, 1);
      for i=1:nVars
        sample_data.variables{i}.name         = vars{i, 1};
        sample_data.variables{i}.typeCastFunc = str2func(netcdf3ToMatlabType(imosParameters(vars{i, 1}, 'type')));
        sample_data.variables{i}.dimensions   = vars{i, 2};
        if ~isempty(vars{i, 2}) % we don't want this for scalar variables
          if length(sample_data.variables{i}.dimensions) == 2
            sample_data.variables{i}.coordinates = ['TIME LATITUDE LONGITUDE ' sample_data.dimensions{sample_data.variables{i}.dimensions(2)}.name];
          else
            sample_data.variables{i}.coordinates = 'TIME LATITUDE LONGITUDE NOMINAL_DEPTH';
          end
        end
        sample_data.variables{i}.data         = sample_data.variables{i}.typeCastFunc(vars{i, 3});
      end
      
    end
    
    function sample_data = awacParse(app)
      %AWACPARSE Parses ADCP data from a raw Nortek AWAC binary (.wpr) file. If
      % processed wave data files (.whd and .wap) are present, these are also
      % parsed.
      %
      % Parses a raw binary file from a Nortek AWAC ADCP. If processed wave data
      % files (.whd and .wap) are present, these are also parsed, to provide wave
      % data. Wave data is not read from the raw binary files, as the raw binary
      % only contains raw wave data. The Nortek software performs a significant
      % amount of processing on this raw wave data to provide standared wave
      % metrics such as significant wave height, period, etc.
      %
      % If wave data is present, it is returned as a separate sample_data struct,
      % due to the fact that the timestamps for wave data are significantly
      % different from the profile and sensor data.
      %
      % Inputs:
      %   filename    - Cell array containing the name of the raw AWAC file
      %                 to parse.
      %   tMode       - Toolbox data type mode.
      %
      % Outputs:
      %   sample_data - Struct containing sample data; If wave data is present,
      %                 this will be a cell array of two structs.
      %
      % Author: 		Paul McCarthy <paul.mccarthy@csiro.au>
      % Contributor: 	Guillaume Galibert <guillaume.galibert@utas.edu.au>
      %
      
      %
      % Copyright (C) 2017, Australian Ocean Data Network (AODN) and Integrated
      % Marine Observing System (IMOS).
      %
      % This program is free software: you can redistribute it and/or modify
      % it under the terms of the GNU General Public License as published by
      % the Free Software Foundation version 3 of the License.
      %
      % This program is distributed in the hope that it will be useful,
      % but WITHOUT ANY WARRANTY; without even the implied warranty of
      % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the
      % GNU General Public License for more details.
      
      % You should have received a copy of the GNU General Public License
      % along with this program.
      % If not, see <https://www.gnu.org/licenses/gpl-3.0.en.html>.
      %
      
      % only one file supported
      filename = app.Archivo.Value;
      
      % read in all of the structures in the raw file
      structures = readParadoppBinary(filename);
      
      % first three sections are header, head and user configuration
      hardware = structures.Id5;
      head     = structures.Id4;
      user     = structures.Id0;
      
      % the rest of the sections are awac data
      
      velocityProcessed = false;
      if isfield(structures, 'Id106')
        % velocity has been processed
        velocityProcessed = true;
      end
      
      %
      % calculate distance values from metadata. See continentalParse.m
      % inline comments for a brief discussion of this process
      %
      % http://www.nortek-as.com/en/knowledge-center/forum/hr-profilers/736804717
      %
      freq       = head.Frequency; % this is in KHz
      blankDist  = user.T2;        % counts
      cellSize   = user.BinLength; % counts
      ncells     = user.NBins;
      factor     = 0;              % used in conversion
      
      switch freq
        case 600,  factor = 0.0797;
        case 1000, factor = 0.0478;
      end
      
      cellSize  = (cellSize / 256) * factor * cos(25 * pi / 180);
      blankDist = blankDist        * 0.0229 * cos(25 * pi / 180) - cellSize;
      
      distance = (blankDist:  ...
        cellSize: ...
        blankDist + (ncells-1) * cellSize)';
      
      % Note this is actually the distance between the ADCP's transducers and the
      % middle of each cell
      % See http://www.nortek-bv.nl/en/knowledge-center/forum/current-profilers-and-current-meters/579860330
      distance = distance + cellSize;
      
      % retrieve sample data
      time            = [structures.Id32(:).Time]';
      analn1          = [structures.Id32(:).Analn1]';
      battery         = [structures.Id32(:).Battery]';
      soundSpeed      = [structures.Id32(:).Analn2]';
      heading         = [structures.Id32(:).Heading]';
      pitch           = [structures.Id32(:).Pitch]';
      roll            = [structures.Id32(:).Roll]';
      status          = [structures.Id32(:).Status]';
      pressure        = [structures.Id32(:).PressureMSB]'*65536 + [structures.Id32(:).PressureLSW]';
      temperature     = [structures.Id32(:).Temperature]';
      velocity1       = [structures.Id32(:).Vel1]';
      velocity2       = [structures.Id32(:).Vel2]';
      velocity3       = [structures.Id32(:).Vel3]';
      backscatter1    = [structures.Id32(:).Amp1]';
      backscatter2    = [structures.Id32(:).Amp2]';
      backscatter3    = [structures.Id32(:).Amp3]';
      
      if velocityProcessed
        % velocity has been processed
        timeProc = [structures.Id106(:).Time]';
        iCommonTime = ismember(time, timeProc); % timeProc can be shorter than time
        
        velocity1Proc(iCommonTime, :)    = [structures.Id106(:).Vel1]'; % tilt effect corrected velocity
        velocity2Proc(iCommonTime, :)    = [structures.Id106(:).Vel2]';
        velocity3Proc(iCommonTime, :)    = [structures.Id106(:).Vel3]';
        sig2noise1(iCommonTime, :)       = [structures.Id106(:).Snr1]';
        sig2noise2(iCommonTime, :)       = [structures.Id106(:).Snr2]';
        sig2noise3(iCommonTime, :)       = [structures.Id106(:).Snr3]';
        stdDev1(iCommonTime, :)          = [structures.Id106(:).Std1]'; % currently not used
        stdDev2(iCommonTime, :)          = [structures.Id106(:).Std2]';
        stdDev3(iCommonTime, :)          = [structures.Id106(:).Std3]';
        errorCode1(iCommonTime, :)       = [structures.Id106(:).Erc1]'; % error codes for each cell in one beam, values between 0 and 4.
        errorCode2(iCommonTime, :)       = [structures.Id106(:).Erc2]';
        errorCode3(iCommonTime, :)       = [structures.Id106(:).Erc3]';
        speed(iCommonTime, :)            = [structures.Id106(:).speed]';
        direction(iCommonTime, :)        = [structures.Id106(:).direction]';
        verticalDist(iCommonTime, :)     = [structures.Id106(:).verticalDistance]'; % ? no idea what this is, always same values between 6000 and 65534 for each profile.
        profileErrorCode(iCommonTime, :) = [structures.Id106(:).profileErrorCode]'; % error codes for each cell of a velocity profile inferred from the 3 beams. 0=good; otherwise error. See http://www.nortek-as.com/en/knowledge-center/forum/waves/20001875?b_start=0#769595815
        qcFlag(iCommonTime, :)           = [structures.Id106(:).qcFlag]'; % QUARTOD QC result. 0=not eval; 1=bad; 2=questionable; 3=good.
      end
      clear structures;
      
      % battery     / 10.0   (0.1 V    -> V)
      % soundSpeed  / 10.0   (0.1 m/s  -> m/s)
      % heading     / 10.0   (0.1 deg  -> deg)
      % pitch       / 10.0   (0.1 deg  -> deg)
      % roll        / 10.0   (0.1 deg  -> deg)
      % pressure    / 1000.0 (mm       -> m)   assuming equivalence to dbar
      % temperature / 100.0  (0.01 deg -> deg)
      % velocities  / 1000.0 (mm/s     -> m/s) assuming earth coordinates
      battery      = battery      / 10.0;
      soundSpeed   = soundSpeed   / 10.0;
      heading      = heading      / 10.0;
      pitch        = pitch        / 10.0;
      roll         = roll         / 10.0;
      pressure     = pressure     / 1000.0;
      temperature  = temperature  / 100.0;
      velocity1    = velocity1    / 1000.0;
      velocity2    = velocity2    / 1000.0;
      velocity3    = velocity3    / 1000.0;
      
      if velocityProcessed
        % velocity has been processed
        % velocities  / 1000.0 (mm/s     -> m/s) assuming earth coordinates
        % 20*log10(sig2noise)  (counts   -> dB)
        % direction   / 100.0  (0.01 deg  -> deg)
        velocity1     = velocity1Proc / 1000.0; % we update the velocity
        velocity2     = velocity2Proc / 1000.0;
        velocity3     = velocity3Proc / 1000.0;
        sig2noise1(sig2noise1==0) = NaN;
        sig2noise2(sig2noise2==0) = NaN;
        sig2noise3(sig2noise3==0) = NaN;
        sig2noise1    = 20*log10(sig2noise1);
        sig2noise2    = 20*log10(sig2noise2);
        sig2noise3    = 20*log10(sig2noise3);
        stdDev1       = stdDev1 / 1000.0; % same unit as velocity I suppose
        stdDev2       = stdDev2 / 1000.0;
        stdDev3       = stdDev3 / 1000.0;
        speed         = speed / 1000.0; % same unit as velocity I suppose
        direction     = direction / 100.0;
        verticalDist  = verticalDist / 1000.0; % since verticalDist is uint16, max value gives 65m but distance along beams can go up to 170m...???
      end
      
      sample_data = struct;
      
      sample_data.toolbox_input_file              = filename;
      sample_data.meta.featureType                = ''; % strictly this dataset cannot be described as timeSeriesProfile since it also includes timeSeries data like TEMP
      sample_data.meta.head                       = head;
      sample_data.meta.hardware                   = hardware;
      sample_data.meta.user                       = user;
      sample_data.meta.binSize                    = cellSize;
      sample_data.meta.instrument_make            = 'Nortek';
      sample_data.meta.instrument_model           = 'AWAC';
      sample_data.meta.instrument_serial_no       = hardware.SerialNo;
      sample_data.meta.instrument_sample_interval = median(diff(time*24*3600));
      sample_data.meta.instrument_average_interval= user.AvgInterval;
      sample_data.meta.instrument_firmware        = hardware.FWversion;
      sample_data.meta.beam_angle                 = 25;   % http://www.hydro-international.com/files/productsurvey_v_pdfdocument_19.pdf
      sample_data.meta.beam_to_xyz_transform      = head.TransformationMatrix;
      
      % add dimensions with their data mapped
      adcpOrientations = single(bitget(status,1,'uint8'));
      adcpOrientation = mode(adcpOrientations); % hopefully the most frequent value reflects the orientation when deployed
      height = distance;
      if adcpOrientation == 1
        % case of a downward looking ADCP -> negative values
        height = -height;
        distance = -distance;
      end
      
      iBadOriented = adcpOrientations ~= adcpOrientation; % we'll only keep velocity data collected when ADCP is oriented as expected
      velocity1(iBadOriented, :) = NaN;
      velocity2(iBadOriented, :) = NaN;
      velocity3(iBadOriented, :) = NaN;
      backscatter1(iBadOriented, :) = NaN;
      backscatter2(iBadOriented, :) = NaN;
      backscatter3(iBadOriented, :) = NaN;
      if velocityProcessed
        sig2noise1(iBadOriented, :) = NaN;
        sig2noise2(iBadOriented, :) = NaN;
        sig2noise3(iBadOriented, :) = NaN;
        stdDev1(iBadOriented, :) = NaN;
        stdDev2(iBadOriented, :) = NaN;
        stdDev3(iBadOriented, :) = NaN;
        errorCode1(iBadOriented, :) = NaN;
        errorCode2(iBadOriented, :) = NaN;
        errorCode3(iBadOriented, :) = NaN;
        speed(iBadOriented, :) = NaN;
        direction(iBadOriented, :) = NaN;
        verticalDist(iBadOriented, :) = NaN;
        profileErrorCode(iBadOriented, :) = NaN;
        qcFlag(iBadOriented, :) = NaN;
      end
      
      dims = {
        'TIME',             time,     ['Time stamp corresponds to the start of the measurement which lasts ' num2str(user.AvgInterval) ' seconds.']; ...
        'DIST_ALONG_BEAMS', distance, ['Values correspond to the distance between the instrument''s transducers and the centre of each cells. ' ...
        'Nortek instrument data is not vertically bin-mapped (no tilt correction applied). Cells are lying parallel to the beams, ' ...
        'at heights above sensor that vary with tilt.']
        };
      clear time distance;
      
      if velocityProcessed
        % we re-arrange dimensions like for RDI ADCPs
        dims(end+1, :) = dims(end, :);
        dims(end-1, :) = {
          'HEIGHT_ABOVE_SENSOR', height(:), ['Values correspond to the distance between the instrument''s transducers and the centre of each cells. ' ...
          'Data has been vertically bin-mapped using Nortek Storm software ''Remove tilt effects'' procedure. Cells have consistant heights above sensor in time.']
          };
      end
      clear height;
      
      nDims = size(dims, 1);
      sample_data.dimensions = cell(nDims, 1);
      for i=1:nDims
        sample_data.dimensions{i}.name         = dims{i, 1};
        sample_data.dimensions{i}.typeCastFunc = str2func(netcdf3ToMatlabType(imosParameters(dims{i, 1}, 'type')));
        sample_data.dimensions{i}.data         = sample_data.dimensions{i}.typeCastFunc(dims{i, 2});
        sample_data.dimensions{i}.comment      = dims{i, 3};
      end
      clear dims;
      
      % add information about the middle of the measurement period
      sample_data.dimensions{1}.seconds_to_middle_of_measurement = user.AvgInterval/2;
      
      % add variables with their dimensions and data mapped.
      if velocityProcessed
        % velocity has been processed
        iDimVel = nDims-1;
        iDimDiag = nDims;
      else
        iDimVel = nDims;
        iDimDiag = nDims;
      end
      switch user.CoordSystem
        case 0 % ENU
          vel2Name = 'VCUR_MAG'; % we assume no correction for magnetic declination has been applied
          vel1Name = 'UCUR_MAG';
          vel3Name = 'WCUR';
          
        case 2 % Beam
          vel2Name = 'VEL2';
          vel1Name = 'VEL1';
          vel3Name = 'VEL3';
          
        otherwise
          error([mfilename ' only supports ENU and Beam coordinate systems']);
      end
      vars = {
        'TIMESERIES',       [],             1; ...
        'LATITUDE',         [],             NaN; ...
        'LONGITUDE',        [],             NaN; ...
        'NOMINAL_DEPTH',    [],             NaN; ...
        vel2Name,           [1 iDimVel],    velocity2; ...
        vel1Name,           [1 iDimVel],    velocity1; ...
        vel3Name,           [1 iDimVel],    velocity3; ...
        'ABSIC1',           [1 iDimDiag],   backscatter1; ...
        'ABSIC2',           [1 iDimDiag],   backscatter2; ...
        'ABSIC3',           [1 iDimDiag],   backscatter3; ...
        'TEMP',             1,              temperature; ...
        'PRES_REL',         1,              pressure; ...
        'VOLT',             1,              battery; ...
        'SSPD',             1,              soundSpeed; ...
        'PITCH',            1,              pitch; ...
        'ROLL',             1,              roll; ...
        'HEADING_MAG',      1,              heading
        };
      clear analn1 analn2 time distance velocity1 velocity2 velocity3 ...
        backscatter1 backscatter2 backscatter3 soundSpeed ...
        temperature pressure battery pitch roll heading status;
      
      if velocityProcessed
        % velocity has been processed
        vars = [vars; {
          'SNR1',                [1 iDimDiag], sig2noise1; ...
          'SNR2',                [1 iDimDiag], sig2noise2; ...
          'SNR3',                [1 iDimDiag], sig2noise3; ...
          %         'STDB1',               [1 iDimDiag], stdDev1; ... % currently not used
          %         'STDB2',               [1 iDimDiag], stdDev2; ...
          %         'STDB3',               [1 iDimDiag], stdDev3; ...
          'NORTEK_ERR1',         [1 iDimDiag], errorCode1; ...
          'NORTEK_ERR2',         [1 iDimDiag], errorCode2; ...
          'NORTEK_ERR3',         [1 iDimDiag], errorCode3; ...
          'CSPD',                [1 iDimVel],  speed; ...
          'CDIR_MAG',            [1 iDimVel],  direction; ...
          %         'VERT_DIST',           [1 iDimVel],  verticalDist; ... % don't know what this is
          'NORTEK_PROFILE_ERR',  [1 iDimVel],  profileErrorCode; ...
          'NORTEK_QC',           [1 iDimVel],  qcFlag
          }];
        clear sig2noise1 sig2noise2 sig2noise3 stdDev1 stdDev2 stdDev3 ...
          errorCode1 errorCode2 errorCode3 speed direction verticalDist ...
          profileErrorCode qcFlag;
      end
      
      nVars = size(vars, 1);
      sample_data.variables = cell(nVars, 1);
      for i=1:nVars
        sample_data.variables{i}.name         = vars{i, 1};
        sample_data.variables{i}.typeCastFunc = str2func(netcdf3ToMatlabType(imosParameters(vars{i, 1}, 'type')));
        sample_data.variables{i}.dimensions   = vars{i, 2};
        if ~isempty(vars{i, 2}) % we don't want this for scalar variables
          if length(sample_data.variables{i}.dimensions) == 2
            sample_data.variables{i}.coordinates = ['TIME LATITUDE LONGITUDE ' sample_data.dimensions{sample_data.variables{i}.dimensions(2)}.name];
          else
            sample_data.variables{i}.coordinates = 'TIME LATITUDE LONGITUDE NOMINAL_DEPTH';
          end
        end
        sample_data.variables{i}.data         = sample_data.variables{i}.typeCastFunc(vars{i, 3});
      end
      clear vars;
      
      %
      % if wave data files are present, read them in
      %
      waveData = readAWACWaveAscii(filename);
      
      % no wave data, no problem
      if isempty(waveData), return; end
      
      % add wave data to existing sample_data
      sample_data = addAWACWaveToSample(sample_data, waveData, filename);
      
    end
    
    function V = adcpLQ(app)
      E={'E0','E1','E2','E3','E4','E5','E6','E7','E0'};
      W={'W0','W1','W2','W3','W4'};
      fid=fopen(app.Archivo.Value,'rb');
      data=fread(fid,inf,'*uint8');
      fclose(fid);
      id1=data(1:end-1)==36;
      id2=data(2:end)==35;
      idx=find(id1 & id2);
      ie=0;in=0;io=0;
      for j=1:length(idx)
        p1=0;p2=p1+7;
        a=data(idx(j)+p1:idx(j)+p2);
        a=char(a(a~=0))';
        switch a
          case '$#FQ.ENS'
            ie=ie+1;
            % header
            n=32;
            word=zeros(n,1);
            for w=1:n
              p1=p2+1;p2=p1+1;
              word(w)=bytecast(data(idx(j)+p1:idx(j)+p2),'L','int16','L');
              if isequal(w,2)
                f=bytecast(data(idx(j)+p1:idx(j)+p2+2),'L','int32','L');
              end
            end
            Ens.Fecha(ie,1)=f/3600/24+datenum(1970,1,1);
            Ens.Ens(ie,1)=word(1);
            Ens.temp(ie,1)=word(4)/10;
            Ens.battery(ie,1)=word(5)/10;
            Ens.npings(ie,1)=word(6);
            Ens.bins_size(ie,1)=word(17)/100; % metros
            Ens.depth(ie,1)=word(26); %en metros
            Ens.blank(ie,1)=word(27)/100; %en metros
            Ens.error_code(ie,1)=word(28);
            Ens.rph_abnormal(ie,1)=word(29);
            % lectura datos
            ens=true;
            while ens
              p1=p2+1;p2=p1+1;
              a=data(idx(j)+p1:idx(j)+p2);a=char(a(a~=0))';
              if any(strcmp(E,a))
                p1=p2+1;p2=p1+1;
                n=bytecast(data(idx(j)+p1:idx(j)+p2),'L','int16','L');
                word=zeros(n,1);
                for w=1:n
                  p1=p2+1;p2=p1+1;
                  word(w)=bytecast(data(idx(j)+p1:idx(j)+p2),'L','int16','L');
                end
                switch a
                  case 'E0'
                    word=word/100;
                    Ens.mr(ie,1)=word(1);
                    Ens.mp(ie,1)=word(2);
                    Ens.mh(ie,1)=word(3);
                    Ens.std_r(ie,1)=word(4);
                    Ens.std_p(ie,1)=word(5);
                    Ens.std_h(ie,1)=word(6);
                  case 'E1'
                    C=reshape(word/100,3,[]);
                    Ens.Roll(ie,1:n/3)=C(1,:);
                    Ens.Pitch(ie,1:n/3)=C(2,:);
                    Ens.Heading(ie,1:n/3)=C(3,:);
                  case 'E2'
                    C=reshape(word/100',[],4)';
                    Ens.vrad_ch0(ie,1:n/4)=C(1,:);
                    Ens.vrad_ch1(ie,1:n/4)=C(2,:);
                    Ens.vrad_ch2(ie,1:n/4)=C(3,:);
                    Ens.vrad_ch3(ie,1:n/4)=C(4,:);
                  case 'E3'
                    C=reshape(word,3,[]);
                    Ens.Vx(ie,1:n/3)=C(1,:);
                    Ens.Vy(ie,1:n/3)=C(2,:);
                    Ens.Vz(ie,1:n/3)=C(3,:);
                  case 'E4'
                    C=reshape(word/100,3,[]);
                    Ens.VNS(ie,1:n/3)=C(1,:);
                    Ens.VEW(ie,1:n/3)=C(2,:);
                    Ens.VUD(ie,1:n/3)=C(3,:);
                  case 'E5'
                    Ens.goog_ping(ie,1:n)=word';
                  case 'E6'
                    C=reshape(word/100',[],4)';
                    Ens.Signal_ch0(ie,1:n/4)=C(1,:);
                    Ens.Signal_ch1(ie,1:n/4)=C(2,:);
                    Ens.Signal_ch2(ie,1:n/4)=C(3,:);
                    Ens.Signal_ch3(ie,1:n/4)=C(4,:);
                  case 'E7'
                    C=reshape(word/100',[],4)';
                    Ens.SNR_ch0(ie,1:n/4)=C(1,:);
                    Ens.SNR_ch1(ie,1:n/4)=C(2,:);
                    Ens.SNR_ch2(ie,1:n/4)=C(3,:);
                    Ens.SNR_ch3(ie,1:n/4)=C(4,:);
                  case 'E8'
                    Ens.pressure(ie,1:4)=word;
                end
              else
                ens=false;
              end
            end
          case '$#FQ.NAV'
            p1=p2+1;p2=p1+1;
            n=bytecast(data(idx(j)+p1:idx(j)+p2),'L','int16','L');
            word=zeros(n,1);
            for w=1:n
              p1=p2+1;p2=p1+1;
              word(w)=bytecast(data(idx(j)+p1:idx(j)+p2),'L','int16','L');
              if isequal(w,2)
                f=bytecast(data(idx(j)+p1:idx(j)+p2+2),'L','int32','L');
              end
            end
            io=io+1;
            Nav.ens(in,1)=word(1);
            Nav.Fecha(in,1)=f/3600/24+datenum(1970,1,1);
            Nav.error(in,1)=word(4);
            Nav.good_bt(in,1:4)=word(5:8);
            Nav.depth(in,1:4)=word(9:12)/10;
            Nav.vrad(in,1:4)=word(13:16)/100;
            Nav.bt_ping_num(in,1)=word(30);
            Nav.volt(in,1)=word(31)/10;
            Nav.vins(in,1:4)=word(36:39)/100;
            Nav.vnav(in,1:4)=word(40:43)/100;
            Nav.rph(in,1:4)=word(44:47)/10;
            Nav.bott_estimate(in,1)=word(48);
            Nav.temp(in,1)=word(49)/10;
          case '$#FQ.DWS'
            n=32;
            word=zeros(n,1);
            for w=1:n
              p1=p2+1;p2=p1+1;
              word(w)=bytecast(data(idx(j)+p1:idx(j)+p2),'L','int16','L');
              if isequal(w,2)
                f=bytecast(data(idx(j)+p1:idx(j)+p2+2),'L','int32','L');
              end
            end
            io=io+1;
            Olas.ens(io,1)=word(1);
            Olas.Fecha(io,1)=f/3600/24+datenum(1970,1,1);
            Olas.Temp(io,1)=word(4)/10;
            Olas.depth(io,1)=word(20);
            Olas.PingPeriod(io,1)=word(24);
            Olas.NPing(io,1)=word(25);
            Olas.FFT(io,1)=word(27);
            ola=true;
            while ola
              p1=p2+1;p2=p1+1;
              a=data(idx(j)+p1:idx(j)+p2);a=char(a(a~=0))';
              if any(strcmp(W,a))
                p1=p2+1;p2=p1+1;
                n=bytecast(data(idx(j)+p1:idx(j)+p2),'L','int16','L');
                word=zeros(n,1);
                for w=1:n
                  p1=p2+1;p2=p1+1;
                  word(w)=bytecast(data(idx(j)+p1:idx(j)+p2),'L','int16','L');
                end
                switch a
                  case 'W0'
                    Olas.Fp(io,1:10)=word(1:10)';
                    Olas.Tp(io,1:10)=word(11:20);
                    Olas.Sp(io,1:10)=word(21:30);
                    Olas.H(io,1:5)=word(31:35);
                    Olas.T(io,1:5)=word(36:40);
                    Olas.RollProm(io,1)=word(41);
                    Olas.PitchProm(io,1)=word(42);
                    Olas.HeadingProm(io,1)=word(43);
                    Olas.RollStd(io,1)=word(44);
                    Olas.PitchStd(io,1)=word(45);
                    Olas.HeadingStd(io,1)=word(46);
                    Olas.FrecProm(io,1)=word(47);
                    Olas.NPeaksValid(io,1)=word(49);
                    Olas.NWaveDir(io,1)=word(50);
                    Olas.SpectrumShift(io,1)=word(51);
                    Olas.WaterLevel(io,1:5)=word(52:56);
                  case 'W1'
                  case 'W2'
                  case 'W3'
                    Olas.WS(io,1:n)=word';
                  case 'W4'
                    ni=n/3;
                    Olas.WSF(io,1:ni)=word(1:ni);
                    Olas.WSD(io,1:ni)=word(ni+1:ni*2)/10;
                    Olas.WSS(io,1:ni)=word(ni*2+1:ni*3);
                end
              else
                ola=false;
              end
            end
            
        end
      end
      if exist('Ens','var')
        V.Ens=Ens;
      end
      if exist('Nav','var')
        V.Nav=Nav;
      end
      if exist('Olas','var')
        V.Olas=Olas;
      end
      
    end
    
    function [Nodo,Variables,Datos]= lee_aanderaa(app)
      %
      % Lectura de la configuracion
      %
      [Cam,~,~]=fileparts(app.Archivo.Value);
      xml=fullfile(Cam,'Config.xml');
      if ~exist(xml,'file')
        [A,C]=uigetfile('*.xml','Archivo de Configuracion xml?');
        if isequal(A,0)
          disp('No se ha seleccionado un archivo')
          return
        end
        xml=fullfile(C,A);
      end
      S=xml2struct(xml);
      C=split(S.Attributes(1).Value,'-');
      k1=0;
      Nodo=cell(1,1);
      for j=1:length(S.Children)
        if contains(S.Children(j).Name,'NodeConfig')
          SN=S.Children(j).Attributes;
          k1=k1+1;
          for ja=1:length(SN)
            Nodo{k1,1}.(SN(ja).Name)=SN(ja).Value;
          end
          SC1=S.Children(j).Children;
          k2=0;
          for jc=1:length(SC1)
            if contains(SC1(jc).Name,'ConfigCategory')
              SA=SC1(jc).Attributes;
              k2=k2+1;
              for ja=1:length(SA)
                Nodo{k1,1}.Config{k2,1}.(SA(ja).Name)=SA(ja).Value;
              end
              SC2=SC1(jc).Children;
              k3=0;
              for ic=1:length(SC2)
                if contains(SC2(ic).Name,'PropertyCategory')
                  k3=k3+1;
                  SA=SC2(ic).Attributes;
                  for ja=1:length(SA)
                    Nodo{k1,1}.Config{k2,1}.Propiedades{k3,1}.(SA(ja).Name)= ...
                      SA(ja).Value;
                  end
                  SC3=SC2(ic).Children;
                  k4=0;
                  for i4=1:length(SC3)
                    if contains(SC3(i4).Name,'Property')
                      k4=k4+1;
                      SA=SC3(i4).Attributes;
                      for ja=1:length(SA)
                        Nodo{k1,1}.Config{k2,1}.Propiedades{k3,1}. ...
                          Propiedad{k4,1}.(SA(ja).Name)=SA(ja).Value;
                      end
                      if ~isempty(SC3(i4).Children)
                        Nodo{k1,1}.Config{k2,1}.Propiedades{k3,1}. ...
                          Propiedad{k4,1}.Data=SC3(i4).Children.Data;
                      end
                    end
                  end
                end
              end
            end
          end
        end
      end
      clear S* xml k* j* A C i4 ic
      %
      % Lectura de los datos
      %
      fid=fopen(app.Archivo.Value);
      l=fgetl(fid);
      fclose(fid);
      if contains(l,{'\t' char(9)})
        separador=char(9);
      elseif contains(l,';')
        separador=';';
      else
        error('Arcivo no contine tab o ; como separador de campo');
      end
      lee=false;
      meta=true;
      [C1,C2,C3,C4]=deal(cell(1,1));
      fid=fopen(app.Archivo.Value);
      while ~feof(fid)
        l=fgetl(fid);
        l=erase(l,'"');
        if meta
          if contains(l,'Record')
            Var=split(l,separador);
            nvar=length(Var);
            jv=0;
            meta=false;
            lee=true;
            l=fgetl(fid);
            l=erase(l,'"');
            Datos=cell(1,nvar);
            Variables.Nombres=Var;
            Variables.nvar=nvar;
            Variables.Pre1=C1;
            Variables.Pre2=C2;
            Variables.Pre3=C3;
            Variables.Pre4=C4;
          else
            C=split(l,separador);
            C4=C3;
            C3=C2;
            C2=C1;
            C1=C;
          end
        end
        if lee
          jv=jv+1;
          C=split(l,separador)';
          Datos(jv,:)=C;
        end
      end
      fclose(fid);
      clear fid arc C A jv l l1 lee meta
      
    end
    
    function Sensores=superficie(app,Sensores,Columna,Configuracion,Instrumento)
      %Ba=3.7;
      switch Instrumento.frecuencia
        case 300
          SL=14;
          Alpha=mean([0.062 0.082]);
        case 600
          SL=9;
          Alpha=mean([0.14 0.2]);
        case 1200
          SL=4.8;
          Alpha=mean([0.44 0.66]);
      end
      Rs=NaN(Instrumento.NBeams,Configuracion.NCapas);
      n = ceil(Configuracion.NCapas/2);
      if sum(Sensores.Presion(Sensores.Sel))>0
        pres=true;
      else
        pres=false;
      end
      ir=find(Sensores.Sel);
      nr=length(ir);
      Ball = 0:Configuracion.NCapas-1;
      peakfit=NaN(nr,Instrumento.NBeams);
      for irec=1:nr
        pitch=Sesnores.Pitch(ir(irec));
        roll=Sensores.Roll(ir(irec));
        SVelADCP=Sensores.VelSonido(ir(irec));
        for ibeam=1:Instrumento.NBeams
          agcpall=Columna.Backscatter{ibeam}(ir(irec),:);
          if pres
            SVel = 1449.2+4.6.*Sesnores.Temperatura(ir(irec))-0.055.*...
              (Sensores.Temperatura(ir(irec))^2)+0.00029.*...
              (Sensores.Temperatura(ir(irec))^3)+...
              (1.34-0.01*Sensores.Temperatura(ir(irec)))*(Sesnores.Salinidad(ir(irec))...
              -35)+0.016*Sensores.Presion(ir(irec))/1e4;
            Rv = Configuracion.Capas(1)*(SVelADCP/SVel)+...
              Configuracion.EspesorCapa.*(SVelADCP/SVel).*Ball;
          else
            Rv=Configuracion.Capas;
          end
          switch ibeam
            case 1
              Rs = Rv./(cos((roll+Instrumento.beamAngle)*pi/180).*cos(pitch*pi/180));
            case 2
              Rs = Rv./(cos((roll-Instrumento.beamAngle)*pi/180).*cos(pitch*pi/180));
            case 3
              Rs = Rv./(cos((pitch-Instrumeto.beamAngle)*pi/180).*cos(roll*pi/180));
            case 4
              Rs = Rv./(cos((pitch+Instrumento.beamAngle)*pi/180).*cos(roll*pi/180));
          end
          TL = 20.*log(Rs)+2.*Alpha.*Rs; % transmission losses per bin
          WMV = agcpall - SL + TL;
          WMV = WMV(end-n:end);
          Rs = Rs(end-n:end);
          dRs = 0.1; %(Rs(end)-Rs(1))/ifactor;
          Rsi = Rs(1):dRs:Rs(end);
          WMVi = interp1(Rs,WMV,Rsi,'spline');
          [~, idx] = max(WMVi);
          %    WMVpeak(irec) = WMVi(idx);
          Rspeak = Rsi(idx);
          switch ibeam
            case 1
              Rspeak = Rspeak.*cos((roll+Instrumento.beamAngle)*pi/180).*...
                cos(pitch*pi/180);
            case 2
              Rspeak = Rspeak.*cos((roll-Instrumento.beamAngle)*pi/180).*...
                cos(pitch*pi/180);
            case 3
              Rspeak = Rspeak.*cos((pitch-Instrumento.beamAngle)*pi/180).*...
                cos(roll*pi/180);
            case 4
              Rspeak = Rspeak.*cos((pitch+Instrumento.beamAngle)*pi/180).*...
                cos(roll*pi/180);
          end
          peakfit(irec,ibeam) = Rspeak;
        end
      end
      Rm = gmean(peakfit')'; % mean for the four beams
      %Rmts=gmean(Rm); % scalar mean for time series
      Rmin=gmin(Rm);
      Rmax=gmax(Rm);
      Sensores.Prof=Rm;
      Sensores.Profmin=Rmin;
      Sensores.Profmax=Rmax;
    end
    
    function mensaje(app,np,txt)
      app.Mensajes.Items{np}=txt;
      app.Mensajes.ItemsData(np)=np;
      scroll(app.Mensajes,txt);
      app.Mensajes.Value=np;
      drawnow
    end
    
  end


    methods (Access = private)

        % Value changed function: Marca
        function MarcaValueChanged(app, event)
      clc
      marca = app.Marca.Value;
      switch marca
        case 'RDI'
          filtro={'*.000; *.PD0','Archivos RDI (*.000, *.PD0)'};
        case 'Sontek'
          filtro={'*.adp','Acoustic Doppler Profiler';...
            '*.arg','Argonaut-XR';'*.ctl','Argonaut'};
        case 'Nortek'
          filtro={'*.prf','Aquadopp formtato prf';'*.adp',...
            'Aquadopp formato adp';'*.wpr','AWAC'};
        case 'LinkQuest'
          filtro={'*.dat','FlowQuest *.dat'};
        case 'Aanderaa'
          filtro={'*.csv','Archivo RDCP ASCII Aanderaa *.csv'};
        otherwise
          return
      end
      [arc,cam]=uigetfile(filtro,'Selección del Archivo a Procesar');
      if isequal(arc,0)
        %pos_men([t1 'No se selecciono un archivo a procesar' t2]);
        app.Archivo.Value='';
      else
        app.Archivo.Value=fullfile(cam,arc);
        [cam,arc,~]=fileparts(app.Archivo.Value);
        app.NetCDF.Value=fullfile(cam,[arc '.nc']);
      end
      
        end

        % Value changed function: Coordenadas
        function CoordenadasValueChanged(app, event)
      value = app.Coordenadas.Value;
      switch value
        case 'Geograficas'
          app.Longitud.Enable=true;
          app.LongitudEditFieldLabel.Enable=true;
          app.Latitud.Enable=true;
          app.LatitudEditFieldLabel.Enable=true;
          app.CoordenadaEste.Enable=false;
          app.CoordenadaEsteEditFieldLabel.Enable=false;
          app.CoordenadaNorte.Enable=false;
          app.CoordenadaNorteEditFieldLabel.Enable=false;
          app.Zona.Enable=false;
          app.ZonaDropDownLabel.Enable=false;
          app.NS.Enable=false;
        case 'UTM'
          app.Longitud.Enable=false;
          app.LongitudEditFieldLabel.Enable=false;
          app.Latitud.Enable=false;
          app.LatitudEditFieldLabel.Enable=false;
          app.CoordenadaEste.Enable=true;
          app.CoordenadaEsteEditFieldLabel.Enable=true;
          app.CoordenadaNorte.Enable=true;
          app.CoordenadaNorteEditFieldLabel.Enable=true;
          app.Zona.Enable=true;
          app.ZonaDropDownLabel.Enable=true;
          app.NS.Enable=true;
      end
        end

        % Value changed function: PresionDropDown
        function PresionDropDownValueChanged(app, event)
      Presion = app.PresionDropDown.Value;
      switch Presion
        case 'ADCP'
          filtro=[];
        otherwise
          filtro={'*.nc','Archivo *.nc'};
      end
      if isempty(filtro)
        app.ArchivoPresion.Value='';
        app.ArchivoPresion.Enable=false;
        app.ArchivoPresionEditFieldLabel.Enable=false;
      else
        [arc,cam]=uigetfile(filtro,'Selección del Archivo a Procesar');
        if isequal(arc,0)
          app.ArchivoPresion.Value='';
          app.ArchivoPresion.Enable=false;
          app.ArchivoPresionEditFieldLabel.Enable=false;
        else
          app.ArchivoPresion.Value=fulfile(cam,arc);
          app.ArchivoPresion.Enable=true;
          app.ArchivoPresionEditFieldLabel.Enable=true;
        end
      end
        end

        % Close request function: ImportaDatpsADCPUIFigure
        function ImportaDatpsADCPUIFigureCloseRequest(app, event)
      delete(app)
      
        end

        % Button pushed function: Cerrar
        function CerrarPushed(app, event)
      clc
      ImportaDatpsADCPUIFigureCloseRequest(app,event);
        end

        % Button pushed function: Importar
        function ImportarButtonPushed(app, event)
      clc
      app.Mensajes.Items={''};app.Mensajes.ItemsData=1;
      np=1;
      app.mensaje(np,'Iniciando Importacion')
      drawnow
      [~,~,ext]=fileparts(app.Archivo.Value);
      Configuracion.Proyecto=app.Proyecto.Value;
      Configuracion.Sector=app.Sector.Value;
      Configuracion.Referencia=app.ReferenciaEditField.Value;
      Configuracion.Instalado=app.InstaladoDropDown.Value;
      %
      % coordenadas
      %
      switch app.Coordenadas.Value
        case 'Geograficas'
          Configuracion.Coordenadas='Geograficas';
          a=app.Longitud.Value;
          if isempty(a)
            Configuracion.Longitud=NaN;
          else
            a=strtrim(a);
            if ~isempty(setdiff(a(:,end),double('NSEW')))
              Configuracion.Longitud=str2double(a);
            else
              Configuracion.Longitud=str2angle(a);
            end
          end
          a=app.Latitud.Value;
          if isempty(a)
            Configuracion.Latitud=NaN;
          else
            a=strtrim(a);
            if ~isempty(setdiff(a(:,end),double('NSEW')))
              Configuracion.Latitud=str2double(a);
            else
              Configuracion.Latitud=str2angle(a);
            end
          end
          if isnan(Configuracion.Latitud) || isnan(Configuracion.Longitud)
            [Configuracion.Este,Configuracion.Norte]=deal(NaN);
            Configuracion.Zona='';
          else
            [Configuracion.Este,Configuracion.Norte,Configuracion.Zona]=ll2utm(Configuracion.Latitud,...
              Configuracion.Longitud);
          end
        case 'UTM'
          Configuracion.Coordenadas='UTM';
          a(1)=app.CoordenadaEste.Value;
          a(2)=app.CoordenadaNorte.Value;
          if any(a<0)
            [Configuracion.Latitud,Configuracion.Longitud,Configuracion.Este,Configuracion.Norte]=deal(NaN);
            Configuracion.Zona='';
          else
            Configuracion.Este=a(1);
            Configuracion.Norte=a(2);
            Configuracion.Zona=str2double(app.Zona.Value);
            if strcmp(app.NS.Value,'S')
              Configuracion.Zona=-Configuracion.Zona;
            end
            [Configuracion.Latitud,Configuracion.Longitud]=utm2ll(Configuracion.Este,Configuracion.Norte,...
              Configuracion.Zona);
          end
        otherwise
          Configuracion.Coordenadas='';
          [Configuracion.Latitud,Configuracion.Longitud,Configuracion.Este,Configuracion.Norte]=deal(NaN);
          Configuracion.Zona='';
      end
      
      switch app.Marca.Value
        case 'RDI'
          %
          % RDI
          %
          np=np+1;
          app.mensaje(np,'Importando RDI');
          V=adcprdi(app);
          np=np+1;
          app.mensaje(np,'Pasando a Estructuras');
          %
          % Instrumneto
          Instrumento.Marca='RDI';
          Instrumento.frecuencia=V.fixedLeader.frecuencia;
          Instrumento.NBeams=V.fixedLeader.numBeams;
          Instrumento.beamAngle=V.fixedLeader.beamAngle;
          Instrumento.trans_longitud_pulso=V.fixedLeader.xmitPulseLength/100; %en metros
          Instrumento.traans_lag_dis=V.fixedLeader.transmitLagDistance/100; %en metros
          %
          % configuracion
          %
          Columna.NCapas=V.fixedLeader.numCells;
          Columna.EspesorCapa=V.fixedLeader.depthCellLength/100;
          Columna.Blanking=V.fixedLeader.blankAfterTransmit/100;
          % indica inicio de cada capa
          Columna.Capas(1,1)=V.fixedLeader.bin1Distance/100+Columna.EspesorCapa/2;
          for j=2:Columna.NCapas
            Columna.Capas(j,1)=Columna.Capas(j-1)+Columna.EspesorCapa;
          end
          Configuracion.Orientacion=V.fixedLeader.Orientacion;
          
          %series de tiempo
          [Sensores.Fecha,Columna.Fecha]=deal(datenum(V.variableLeader.y2kCentury*100+...
            V.variableLeader.y2kYear,V.variableLeader.y2kMonth,...
            V.variableLeader.y2kDay,V.variableLeader.y2kHour,...
            V.variableLeader.y2kMinute,V.variableLeader.y2kSecond+...
            V.variableLeader.y2kHundredth/100));
          [Sensores.Sel,Columna.Sel]=deal(true(size(Sensores.Fecha)));
          Sensores.VelSonido=V.variableLeader.speedOfSound;
          Sensores.Prof=V.variableLeader.depthOfTransducer/10;
          Sensores.Profmin=nanmin(Sensores.Prof);
          Sensores.Profmax=nanmax(Sensores.Prof);
          Sensores.Heading=V.variableLeader.heading/100;
          Sensores.Pitch=V.variableLeader.pitch/100;
          Sensores.Roll=V.variableLeader.roll/100;
          Sensores.Salinidad=V.variableLeader.salinity;
          Sensores.Temperatura=V.variableLeader.temperature/100;
          Sensores.Presion=V.variableLeader.pressure/1000;
          %corrientes
          Columna.U=V.velocity.velocity1;Columna.U(Columna.U==-32768)=NaN;
          Columna.U=Columna.U/1000;
          Columna.V=V.velocity.velocity2;Columna.V(Columna.V==-32768)=NaN;
          Columna.V=Columna.V/1000;
          Columna.W=V.velocity.velocity3;Columna.W(Columna.W==-32768)=NaN;
          Columna.W=Columna.W/1000;
          Columna.VE=V.velocity.velocity4;
          Columna.VE(Columna.VE==-32768)=NaN;Columna.VE=Columna.VE/1000;
          Columna.Mag = sqrt(Columna.V.^2 + Columna.U.^2);
          % direction is in degrees clockwise from north
          direction = atan(abs(Columna.U./Columna.V)).*(180/pi);
          % !!! if vvel == 0 we get NaN !!!
          direction(Columna.V == 0) = 90;
          se = Columna.V <  0 & Columna.U>= 0;
          sw = Columna.V <  0 & Columna.U <  0;
          nw = Columna.V >= 0 & Columna.U <  0;
          direction(se) = 180 - direction(se);
          direction(sw) = 180 + direction(sw);
          direction(nw) = 360 - direction(nw);
          Columna.Dir=direction;
          Columna.Backscatter1=V.echoIntensity.field1*0.45;
          Columna.Backscatter2=V.echoIntensity.field2*0.45;
          Columna.Backscatter3=V.echoIntensity.field3*0.45;
          Columna.Backscatter4=V.echoIntensity.field4*0.45;
          Columna.CorrMag1=V.corrMag.field1;
          Columna.CorrMag2=V.corrMag.field2;
          Columna.CorrMag3=V.corrMag.field3;
          Columna.CorrMag4=V.corrMag.field4;
          Columna.PercGood1=V.percentGood.field1;
          Columna.PercGood2=V.percentGood.field2;
          Columna.PercGood3=V.percentGood.field3;
          Columna.PercGood4=V.percentGood.field4;
          if isequal(sum(Sensores.Presion),0) && strcmp(Configuracion.Orientacion,'Up')
            np=np+1;
            app.mensaje(np,'ADCP RDI: Se calcula superficie del Backscaterring');
            Sensores=superficie(app,Sensores,Columna,Configuracion,Instrumento);
          end
        case 'Sontek'
          %
          % SONTEK
          %
          np=np+1;
          app.mensaje(np,'Leyendo Sontek');
          switch ext
            case '.arg'
              V=adcpsontek(app);
            case '.ctl'
              V=adcpsontek_ctl(app);
          end
          np=np+1;
          app.mensaje(np,'Pasando a Estructuras');
          
          Instrumento.Marca='Sontek';
          Instrumento.Modelo=['Argonaut-0f' V.modelo];
          Instrumento.Frecuencia=V.Frecuencia;
          Columna.NCapas=V.n_capas;
          Columna.EspesorCapa=V.c_size;
          % al parecer se refiere al punto medio de la capa
          Columna.Capas=V.capa;
          Columna.Blanking=V.blank;
          [Sensores.Fecha,Columna.Fecha]=deal(V.fecha);nf=length(Sensores.Fecha);
          [Sensores.Sel,Columna.Sel]=deal(true(nf,1));
          Sensores.Heading=V.Heading;
          Sensores.Pitch=V.Pitch;
          Sensores.Roll=V.Roll;
          Sensores.Temperatura=V.Temp;
          Sensores.Presion=V.Presion;
          Sensores.Prof=V.Presion;
          Columna.U=V.u/100;
          Columna.V=V.v/100;
          Columna.W=V.w/100;
          Columna.Mag=V.mag/100;
          Columna.Dir=V.dir;
        case 'Nortek'
          %
          % NORTEK
          %
          np=np+1;
          app.mensaje(np,'Leyendo Nortek');
          switch ext
            case {'.prf','.adp'}
              V = aquadoppProfilerParse(app);
            case '.wpr'
              V = awacParse(app);
          end
          np=np+1;
          app.mensaje(np,'Pasando a Estructuras');
          
          nv=length(V.variables);NV=cell(nv,1);
          for j=1:nv;NV{j}=V.variables{j}.name;end
          %Instrumento
          Instrumento.Marca=V.meta.instrument_make;
          Instrumento.Modelo=V.meta.instrument_model;
          Instrumento.NSerie=V.meta.instrument_serial_no;
          Instrumento.Frecuencia=V.meta.head.Frequency;
          Instrumento.NBeams=V.meta.head.NBeams;
          Instrumento.BeamAngle=V.meta.beam_angle;
          %configuracion
          Configuracion.Intervalo_Muestreo=V.meta.instrument_sample_interval;
          Configuracion.Intervalo_Promedio=V.meta.instrument_average_interval;
          Columna.NCapas=V.meta.user.NBins;
          Columna.EspesorCapa=V.meta.binSize;
          Columna.Capas=double(V.dimensions{2}.data); %distancia al centro de a capa desde transductor
          %ADCP.Blanking=V.Ens.blank;
          %series de tiempo
          [Sensores.Fecha,Columna.Fecha]=deal(V.dimensions{1}.data);nf=length(Sensores.Fecha);
          [Sensores.Sel,Columna.Sel]=deal(true(nf,1));
          jj=find(contains(NV,'HEADING'));
          if isempty(jj)
            Sensores.Heading=nan(nf,1);
          else
            Sensores.Heading=double(V.variables{jj}.data);
          end
          jj=find(contains(NV,'PITCH'));
          if isempty(jj)
            Sensores.Pitch=nan(nf,1);
          else
            Sensores.Pitch=double(V.variables{jj}.data);
          end
          jj=find(contains(NV,'ROLL'));
          if isempty(jj)
            Sensores.Roll=nan(nf,1);
          else
            Sensores.Roll=double(V.variables{jj}.data);
          end
          jj=find(contains(NV,'TEMP'));
          if isempty(jj)
            Sensores.Temperatura=nan(nf,1);
          else
            Sensores.Temperatura=double(V.variables{jj}.data);
          end
          jj=find(contains(NV,'PRES_REL'));
          if isempty(jj)
            Sensores.Presion=nan(nf,1);
          else
            Sensores.Presion=double(V.variables{jj}.data);
          end
          if isnan(Configuracion.Latitud)
            Sensores.Prof=-gsw_z_from_p(Sensores.Presion,0);
          else
            Sensores.Prof=-gsw_z_from_p(Sensores.Presion,Configuracion.Latitud);
          end
          jj=find(contains(NV,'UCUR'));
          if isempty(jj)
            Columna.U=nan(Configuracion.NCapas);
          else
            Columna.U=double(V.variables{jj}.data);
          end
          jj=find(contains(NV,'VCUR'));
          if isempty(jj)
            Columna.V=nan(nf,Configuracion.NCapas);
          else
            Columna.V=double(V.variables{jj}.data);
          end
          jj=find(contains(NV,'WCUR'));
          if isempty(jj)
            Columna.W=nan(nf,configuracion.NCapas);
          else
            Columna.W=double(V.variables{jj}.data);
          end
          Columna.Mag = sqrt(Columna.U.^2 + Columna.V.^2);
          % direction is in degrees clockwise from north
          direction = atan(abs(Columna.U./Columna.V)).*(180/pi);
          % !!! if vvel == 0 we get NaN !!!
          direction(Columna.V == 0) = 90;
          se = Columna.V <  0 & Columna.U>= 0;
          sw = Columna.V <  0 & Columna.U <  0;
          nw = Columna.V >= 0 & Columna.U <  0;
          direction(se) = 180 - direction(se);
          direction(sw) = 180 + direction(sw);
          direction(nw) = 360 - direction(nw);
          Columna.Dir=direction;
        case 'LinkQuest'
          %
          % LinkQuest
          %
          np=np+1;
          app.mensaje(np,'Leyendo LinkQuest');
          V=adcpLQ(app);
          np=np+1;
          app.mensaje(np,'Pasando a Estructuras');
          
          Instrumento.Marca='LinkQuest';
          Instrumento.Modelo='FlowQuest';
          %configuracion
          [~,Columna.NCapas]=size(V.Ens.VNS);
          Columna.EspesorCapa=V.Ens.bins_size(1);
          Columna.Blanking=V.Ens.blank(1);
          % capa definida al punto medio
          Columna.Capas =(Columna.Blanking+Columna.EspesorCapa/2:Columna.EspesorCapa: ...
            (Columna.Blanking+Columna.EspesorCapa + (Columna.NCapas-1)*Columna.EspesorCapa))';
          %series de tiempo
          [Sensores.Fecha,Columna.Fecha]=deal(V.Ens.Fecha);
          [Sensores.Sel,Columna.Sel]=deal(true(size(Sensores.Fecha)));
          Sensores.Prof=V.Ens.depth;
          Sensores.Heading=mean(V.Ens.Heading,2);
          Sensores.Pitch=mean(V.Ens.Pitch,2);
          Sensores.Roll=mean(V.Ens.Roll,2);
          Sensores.Temperatura=V.Ens.temp;
          if isfield(V.Ens,'pressure')
            Sensores.Presion=V.Ens.pressure;
          end
          Columna.U=V.Ens.VEW/1000;
          Columna.V=V.Ens.VNS/1000;
          Columna.W=V.Ens.VUD/1000;
          Columna.Mag = sqrt(Columna.U.^2 + Columna.V.^2);
          % direction is in degrees clockwise from north
          direction = atan(abs(Columna.U./Columna.V)).*(180/pi);
          % !!! if vvel == 0 we get NaN !!!
          direction(Columna.V == 0) = 90;
          se = Columna.V <  0 & Columna.U>= 0;
          sw = Columna.V <  0 & Columna.U <  0;
          nw = Columna.V >= 0 & Columna.U <  0;
          direction(se) = 180 - direction(se);
          direction(sw) = 180 + direction(sw);
          direction(nw) = 360 - direction(nw);
          Columna.Dir=direction;
          if isfield(V,'Nav')
            Navegacion=V.Nav;
          end
          if isfield(V,'Olas')
            Oleaje=V.Olas;
          end
        case 'Aanderaa'
          %
          % Aanderaa
          %
          np=np+1;
          app.mensaje(np,'Leyendo Aanderaa');
          [Nodo,Var,Datos]=lee_aanderaa(app);
          ng=length(Nodo);Grupo=cell(1,1);
          for j=1:ng
            Grupo{j,1}=Nodo{j}.ProdName;
          end
          np=np+1;
          app.mensaje(np,'Pasando a Estructuras');
          
          Instrumento.Marca='Aanderaa';
          Instrumento.Modelo=[Nodo{1}.ProdName ' ' Nodo{1}.ProdNo];
          Instrumento.Nserie=Nodo{1}.SerialNo;
          Instrumento.Propietario=Nodo{1}.Config{4}.Propiedades{1}.Propiedad{1}.Data;
          jj=contains(Grupo,'Doppler Current Profiler Sensor');
          Configuracion.Superficie=strcmp(Nodo{jj}.Config{3}.Propiedades{5}.Propiedad{1}.Data,'true');
          if Configuracion.Superficie
            Superficie.CellSize=str2double((Nodo{jj}.Config{3}.Propiedades{5}.Propiedad{2}.Data));
          end
          Configuracion.Columna1=strcmp(Nodo{jj}.Config{3}.Propiedades{6}.Propiedad{1}.Data,'true');
          if Configuracion.Columna1 || Configuracion.Superficie
            Columna.EspesorCapa=str2double(Nodo{jj}.Config{3}.Propiedades{6}.Propiedad{2}.Data);
            Columna.Blanking=str2double(Nodo{jj}.Config{3}.Propiedades{6}.Propiedad{3}.Data);
            Columna.NCapas=str2double(Nodo{jj}.Config{3}.Propiedades{6}.Propiedad{4}.Data);
            Sobreposicion=str2double(Nodo{jj}.Config{3}.Propiedades{6}.Propiedad{5}.Data);
            Columna.EspesorCapa=Columna.EspesorCapa*(100-Sobreposicion)/100;
            Columna.Capas(1,1)=Columna.Blanking+Columna.EspesorCapa/2;
            for j=2:Columna.NCapas
              Columna.Capas(j,1)=Columna.Capas(j-1)+Columna.EspesorCapa;
            end
          end
          Configuracion.Columna2=strcmp(Nodo{jj}.Config{3}.Propiedades{7}.Propiedad{1}.Data,'true');
          if Configuracion.Columna2
            Columna2.EspesorCapa=str2double(Nodo{jj}.Config{3}.Propiedades{7}.Propiedad{2}.Data);
            Columna2.Blanking=str2double(Nodo{jj}.Config{3}.Propiedades{7}.Propiedad{3}.Data);
            Columna2.NCapas=str2double(Nodo{jj}.Config{3}.Propiedades{7}.Propiedad{4}.Data);
            Columna2.Sobreposicion=str2double(Nodo{jj}.Config{3}.Propiedades{6}.Propiedad{5}.Data);
            Columna2.Capas=Columna2.Blanking+Columna2.EspesorCapa*(1-Columna2.Sobreposicion/100).*[1:Columna2.NCapas]';
          end
          Configuracion.Columna3=strcmp(Nodo{jj}.Config{3}.Propiedades{8}.Propiedad{1}.Data,'true');
          if Configuracion.Columna3
            Columna3.EspesorCapa=str2double(Nodo{jj}.Config{3}.Propiedades{8}.Propiedad{2}.Data);
            Columna3.Blanking=str2double(Nodo{jj}.Config{3}.Propiedades{8}.Propiedad{3}.Data);
            Columna3.NCapas=str2double(Nodo{jj}.Config{3}.Propiedades{8}.Propiedad{4}.Data);
            Columna3.Sobreposicion=str2double(Nodo{jj}.Config{3}.Propiedades{6}.Propiedad{5}.Data);
            Columna3.Capas=Columna3.Blanking+Columna3.EspesorCapa*(1-Columna3.Sobreposicion/100).*[1:Columna3.NCapas]';
          end
          
          ultimo='';
          [super,col1,col2,col3]=deal(false);
          for j=1:Var.nvar
            v=Var.Nombres{j};
            if ~contains(v,{'Status' 'Record Number'})
              if isempty(v)
                p1=p1+1;
                if olas
                  Oleaje.(ultimo)(:,p1)=str2double(Datos(:,j));
                end
                if corrientes
                  Sensores.(ultimo)(:,p1)=str2double(Datos(:,j));
                end
              elseif contains(v,'Cell')
                switch Var.Pre2{j}
                  case 'Surface'
                    super=true;
                    [col1,col2,col3]=deal(false);
                  case 'Column 1'
                    col1=true;
                    [super,col2,col3]=deal(false);
                  case 'Column 2'
                    col3=true;
                    [super,col1,col3]=deal(false);
                  case 'Column 3'
                    col3=true;
                    [super,col1,col2]=deal(false);
                end
                if super
                  v=Var.Pre1{j};
                  factor=1;
                  if ~contains(v,'Status')
                    if contains(v,{'cm/s' 'Speed'})
                      factor=0.01;
                    end
                    if contains(v,'[')
                      u=char(extractBetween(v,'[',']'));
                      v=extractBefore(v,'[');
                    else
                      u='';
                    end
                    if contains(v,'(')
                      v=extractBefore(v,'(');
                    end
                    v=erase(replace(strtrim(v),' ','_'),'.');
                    Superficie.(v)=str2double(Datos(:,j))*factor;
                  end
                end
                if col1
                  capa=str2double(extractBetween(v,'(',')'))+1;
                  v=Var.Pre1{j};
                  if contains(v,'Status')
                    lee=false;
                  elseif ~isempty(v)
                    lee=true;
                    factor=1;
                    if contains(v,{'cm/s' 'Speed'})
                      factor=0.01;
                    end
                    if contains(v,'[')
                      u=char(extractBetween(v,'[',']'));
                      v=extractBefore(v,'[');
                    else
                      u='';
                    end
                    if contains(v,'(')
                      v=extractBefore(v,'(');
                    end
                    v=erase(replace(strtrim(v),' ','_'),'.');
                    ultimo=v;
                    p1=0;
                  end
                  if lee
                    p1=p1+1;
                    Columna.(ultimo)(:,p1)=str2double(Datos(:,j))*factor;
                  end
                end
                if col2
                  capa=str2double(extractBetween(v,'(',')'))+1;
                  factor=1;
                  v=Var.Pre1{j};
                  if contains(v,'Status')
                    lee=false;
                  elseif ~isempty(v)
                    lee=true;
                    if contains(v,'[')
                      if contains(v,{'cm/s' 'Speed'})
                        factor=0.01;
                      end
                      v=extractBefore(v,'[');
                    end
                    v=erase(replace(strtrim(v),' ','_'),'.');
                    ultimo=v;
                    p1=0;
                  end
                  if lee
                    p1=p1+1;
                    Columna2.(ultimo)(:,p1)=str2double(Datos(:,j))*factor;
                  end
                end
                if col3
                  capa=str2double(extractBetween(v,'(',')'))+1;
                  factor=1;
                  v=Var.Pre1{j};
                  if contains(v,'Status')
                    lee=false;
                  elseif ~isempty(v)
                    lee=true;
                    if contains(v,'[')
                      if contains(v,'cm/s')
                        factor=0.01;
                      end
                      v=extractBefore(v,'[');
                    end
                    v=erase(replace(strtrim(v),' ','_'),'.');
                    ultimo=v;
                    p1=0;
                  end
                  if lee
                    p1=p1+1;
                    Columna3.(ultimo)(:,p1)=str2double(Datos(:,j))*factor;
                  end
                end
              else
                if contains(v,'[')
                  u=char(extractBetween(v,'[',']'));
                  v=extractBefore(v,'[');
                else
                  u='';
                end
                v=erase(replace(strtrim(v),' ','_'),'.');
                switch v
                  case 'Record_Time'
                    f=Datos{1,j};
                    if strcmp(f,datestr(datenum(f,'yyyy-mm-dd HH:MM:SS'),'yyyy-mm-dd HH:MM:SS'))
                      fmt='yyyy-mm-dd HH:MM:SS';
                    elseif strcmp(f,datestr(datenum(f,'mm-dd-yyyy HH:MM:SS'),'mm-dd-yyyy HH:MM:SS'))
                      fmt='mm-dd-yyyy HH:MM:SS';
                    elseif strcmp(f,datestr(datenum(f,'dd-mm-yyyy HH:MM:SS'),'dd-mm-yyyy HH:MM:SS'))
                      fmt='dd-mm-yyyy HH:MM:SS';
                    else
                      error('No se pudo determinar el formato del campo oREcor Time');
                    end
                    [Sensores.Fecha,Columna.Fecha,Superficie.Fecha,Oleaje.Fecha]=deal(...
                      datenum(Datos(:,j),fmt));
                    [Sensores.Sel,Columna.Sel,Superficie.Sel,Oleaje.Sel]=deal(true(size(Sensores.Fecha)));
                    Sensores.HusoHorario='UTC';
                    
                  case {'Pressure' 'Temperature' 'Tide_Pressure' 'Tide_Level' 'Heading' 'Std_Dev_Heading' ...
                      'Pitch' 'Roll' 'Abs_Tilt' 'Max_Tilt' 'Std_Dev_Tilt' 'Tilt_Direction' 'Speed_Of_Sound' ...
                      'Depth' 'Salinity' 'Density' 'Charge_Voltage_Vtx1' 'Charge_Voltage_Vtx2' ...
                      'Min_Input_Voltage' 'Input_Voltage' 'Input_Current' 'Memory_Used' 'Air_Detect' ...
                      'Noise_Peak_Level_B1' 'Noise_Peak_Level_B2' 'Noise_Peak_Level_B3' 'Noise_Peak_Level_B4' ...
                      'Ping_Count' 'EventReg_Values'}
                    Sensores.(v)=str2double(Datos(:,j));
                    if contains(v,'EventReg_Values')
                      ultimo=v;p1=1;corrientes=false;
                    else
                      [corrientes,olas]=deal(false);
                    end
                  case {'Sign_Height' 'Max_Height' 'Mean_Period' 'Peak_Period' 'Energy_Periodo' ...
                      'Mean_Zero_Crossing' 'Steepness' 'Irregularity' 'CutOff_Freq_High' ...
                      'Last_Pressure_Sample_Index' 'Pressure_Series' 'Wave_Spectrum'}
                    Oleaje.(v)=str2double(Datos(:,j));
                    if contains(v,{'Pressure_Series' 'Wave_Spectrum'})
                      ultimo=v;p1=1;olas=true;
                    else
                      [corrientes,olas]=deal(false);
                    end
                end
              end
            end
          end
      end
      np=np+1;
      app.mensaje(np,'Importacion Terminada');
      %
      % Generacion Archivo Netcdf
      %
      np=np+1;
      app.mensaje(np,'Creando Archivo NetCDDF');
      
      nc=app.NetCDF.Value;
      if exist(nc,'file')
        delete(nc);
      end
      ncid=netcdf.create(nc,'NETCDF4');
      if exist('Configuracion','Var')
        childGroupId = netcdf.defGrp(ncid,'Configuracion');
      end
      netcdf.close(ncid);
      %
      % Instrumento
      %
      if exist('Instrumento','Var')
        V=fieldnames(Instrumento);
        for j=1:length(V)
          ncwriteatt(nc,'/', V{j},Instrumento.(V{j}));
        end
      end
      %
      % Grupo Configuracion
      %
      if exist('Configuracion','Var')
        V=fieldnames(Configuracion);
        for j=1:length(V)
          clase=class(Configuracion.(V{j}));
          Dim=size(Configuracion.(V{j}));
          dato=Configuracion.(V{j});
          switch clase
            case 'char'
              if isequal(Dim(1),1)
                ncwriteatt(nc,'/Configuracion/', V{j},dato);
              end
            case 'logical'
              clase='int8';
              dato=int8(dato);
              nccreate(nc,['/Configuracion/' V{j}],'DataType',clase);
              ncwriteatt(nc,'/Configuracion/', V{j},dato);
            otherwise
              if strcmp(V{j},'Capas')
                nccreate(nc,['/Configuracion/' V{j}],'DataType',clase,'Dimensions',...
                  {'Capas',Configuracion.NCapas});
              else
                nccreate(nc,['/Configuracion/' V{j}],'DataType',clase);
              end
              ncwrite(nc,['/Configuracion/' V{j}],dato);
          end
        end
      end
      nf=length(Sensores.Fecha);
      %
      % Datos Sensores
      %
      if exist('Sensores','Var')
        V=fieldnames(Sensores);
        for j=1:length(V)
          if strcmp(V{j},'HusoHorario')
            Var='/Sensores/Fecha';
            ncwriteatt(nc,Var,'Huso Horario',Sensores.(V{j}));
          else
            Var=['/Sensores/' V{j}];
            dato=Sensores.(V{j});
            clase=class(Sensores.(V{j}));
            switch clase
              case 'double'
                if ~strcmp(V{j},'Fecha')
                  clase='single';
                end
              case 'logical'
                clase='int8';
                dato=int8(dato);
            end
            Dim=size(Sensores.(V{j}));
            Dimensiones={'Fecha',nf};
            nccreate(nc,Var,'Dimensions',Dimensiones,'Datatype',clase,'format','netcdf4',...
              'FillValue',NaN,'DeflateLevel',9);
            ncwrite(nc,Var,dato);
          end
        end
      end
      %
      % Datos superficie
      %
      if exist('Superficie','Var')
        V=fieldnames(Superficie);
        for j=1:length(V)
          Var=['/Superficie/' V{j}];
          dato=Superficie.(V{j});
          clase=class(Superficie.(V{j}));
          switch clase
            case 'double'
              if ~strcmp(V{j},'Fecha')
                clase='single';
              end
            case 'logical'
              clase='int8';
              dato=int8(dato);
          end
          Dim=size(Superficie.(V{j}));
          switch Dim(2)
            case 1
              Dimensiones={'Fecha',nf};
            otherwise
              Dimensiones={'Fecha',nf,'Capas',Configuracion.NCapas};
          end
          nccreate(nc,Var,'Dimensions',Dimensiones,'Datatype',clase,'format','netcdf4',...
            'FillValue',NaN,'DeflateLevel',9);
          ncwrite(nc,Var,dato)
        end
      end
      %
      % Dartos Columna
      %
      if exist('Columna','Var')
        V=fieldnames(Columna);
        for j=1:length(V)
          Var=['/Columna/' V{j}];
          dato=Columna.(V{j});
          clase=class(Columna.(V{j}));
          switch clase
            case 'double'
              if ~strcmp(V{j},'Fecha')
                clase='single';
              end
            case 'logical'
              clase='int8';
              dato=int8(dato);
          end
          Dim=size(Columna.(V{j}));
          if isequal(Dim(1),1)
            nccreate(nc,Var,'DataType',clase);
          else
            switch Dim(2)
              case 1
                if isequal(Dim(1),nf)
                  Dimensiones={'Fecha',nf};
                elseif isequal(Dim(1),Columna.NCapas)
                  Dimensiones={'Capas',Columna.NCapas};
                end
              otherwise
                Dimensiones={'Fecha',nf,'Capas',Columna.NCapas};
            end
            nccreate(nc,Var,'Dimensions',Dimensiones,'Datatype',clase,'format','netcdf4',...
              'FillValue',NaN,'DeflateLevel',9);
          end
          ncwrite(nc,Var,dato)
        end
      end
      %
      % Dartos Columna2
      %
      if exist('Columna2','Var')
        V=fieldnames(Columna2);
        for j=1:length(V)
          Var=['/Columna2/' V{j}];
          dato=Columna.(V{j});
          clase=class(Columna2.(V{j}));
          switch clase
            case 'double'
              if ~strcmp(V{j},'Fecha')
                clase='single';
              end
            case 'logical'
              clase='int8';
              dato=int8(dato);
          end
          Dim=size(Columna2.(V{j}));
          switch Dim(2)
            case 1
              Dimensiones={'Fecha',nf};
            otherwise
              Dimensiones={'Fecha',nf,'Capas',Columna2.NCapas};
          end
          nccreate(nc,Var,'Dimensions',Dimensiones,'Datatype',clase,'format','netcdf4',...
            'FillValue',NaN,'DeflateLevel',9);
          ncwrite(nc,Var,dato)
        end
      end
      %
      % Dartos Columna 3
      %
      if exist('Columna3','Var')
        V=fieldnames(Columna3);
        for j=1:length(V)
          Var=['/Columna3/' V{j}];
          dato=Columna.(V{j});
          clase=class(Columna3.(V{j}));
          switch clase
            case 'double'
              if ~strcmp(V{j},'Fecha')
                clase='single';
              end
            case 'logical'
              clase='int8';
              dato=int8(dato);
          end
          Dim=size(Columna3.(V{j}));
          switch Dim(2)
            case 1
              Dimensiones={'Fecha',nf};
            otherwise
              Dimensiones={'Fecha',nf,'Capas',Columna3.NCapas};
          end
          nccreate(nc,Var,'Dimensions',Dimensiones,'Datatype',clase,'format','netcdf4',...
            'FillValue',NaN,'DeflateLevel',9);
          ncwrite(nc,Var,dato)
        end
      end
      %
      % Oleaje
      %
      if exist('Oleaje','Var')
        V=fieldnames(Oleaje);
        kk=0;
        for j=1:length(V)
          Var=['/Oleaje/' V{j}];
          dato=Oleaje.(V{j});
          clase=class(Oleaje.(V{j}));
          switch clase
            case 'double'
              if ~strcmp(V{j},'Fecha')
                clase='single';
              end
            case 'logical'
              clase='int8';
              dato=int8(dato);
          end
          Dim=size(Oleaje.(V{j}));
          switch Dim(2)
            case 1
              Dimensiones={'Fecha',nf};
            otherwise
              kk=kk+1;
              Dimensiones={'Fecha',nf,['Dim_' num2str(kk)],Dim(2)};
          end
          nccreate(nc,Var,'Dimensions',Dimensiones,'Datatype',clase,'format','netcdf4',...
            'FillValue',NaN,'DeflateLevel',9);
          ncwrite(nc,Var,dato)
        end
      end
      np=np+1;
      app.mensaje(np,'Archivo NetCDF Creado!!!!');
      
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportaDatpsADCPUIFigure = uifigure;
            app.ImportaDatpsADCPUIFigure.Name = 'Importa Datps ADCP';
            app.ImportaDatpsADCPUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImportaDatpsADCPUIFigureCloseRequest, true);
            app.MarcaDropDownLabel = uilabel(app.ImportaDatpsADCPUIFigure);
            app.MarcaDropDownLabel.Text = 'Marca';
            app.Marca = uidropdown(app.ImportaDatpsADCPUIFigure);
            app.Marca.Items = {'', 'RDI', 'Sontek', 'Nortek', 'LinkQuest', 'Aanderaa'};
            app.Marca.ValueChangedFcn = createCallbackFcn(app, @MarcaValueChanged, true);
            app.Marca.Value = '';
            app.ArchivoEditFieldLabel = uilabel(app.ImportaDatpsADCPUIFigure);
            app.ArchivoEditFieldLabel.Text = 'Archivo';
            app.Archivo = uieditfield(app.ImportaDatpsADCPUIFigure, 'text');
            app.Archivo.Editable = 'off';
            app.Mensajes = uilistbox(app.ImportaDatpsADCPUIFigure);
            app.Mensajes.Items = {''};
            app.Mensajes.Value = '';
            app.MetadataPanel = uipanel(app.ImportaDatpsADCPUIFigure);
            app.MetadataPanel.Title = 'Metadata';
            app.CoordenadasDropDownLabel = uilabel(app.MetadataPanel);
            app.CoordenadasDropDownLabel.Text = 'Coordenadas';
            app.Coordenadas = uidropdown(app.MetadataPanel);
            app.Coordenadas.Items = {'Geograficas', 'UTM'};
            app.Coordenadas.ValueChangedFcn = createCallbackFcn(app, @CoordenadasValueChanged, true);
            app.Coordenadas.Value = 'Geograficas';
            app.ProyectoEditFieldLabel = uilabel(app.MetadataPanel);
            app.ProyectoEditFieldLabel.Text = 'Proyecto';
            app.Proyecto = uieditfield(app.MetadataPanel, 'text');
            app.Proyecto.Value = '  ';
            app.SectorEditFieldLabel = uilabel(app.MetadataPanel);
            app.SectorEditFieldLabel.Text = 'Sector';
            app.Sector = uieditfield(app.MetadataPanel, 'text');
            app.Sector.Value = '  ';
            app.ReferenciaEditFieldLabel = uilabel(app.MetadataPanel);
            app.ReferenciaEditFieldLabel.Text = 'Referencia';
            app.ReferenciaEditField = uieditfield(app.MetadataPanel, 'text');
            app.ReferenciaEditField.Value = '  ';
            app.InstaladoDropDownLabel = uilabel(app.MetadataPanel);
            app.InstaladoDropDownLabel.Text = 'Instalado';
            app.InstaladoDropDown = uidropdown(app.MetadataPanel);
            app.InstaladoDropDown.Items = {'Superficie', 'Media Agua', 'Fondo'};
            app.InstaladoDropDown.Value = 'Fondo';
            app.LongitudEditFieldLabel = uilabel(app.MetadataPanel);
            app.LongitudEditFieldLabel.Text = 'Longitud';
            app.Longitud = uieditfield(app.MetadataPanel, 'text');
            app.LatitudEditFieldLabel = uilabel(app.MetadataPanel);
            app.LatitudEditFieldLabel.Text = 'Latitud';
            app.Latitud = uieditfield(app.MetadataPanel, 'text');
            app.CoordenadaEsteEditFieldLabel = uilabel(app.MetadataPanel);
            app.CoordenadaEsteEditFieldLabel.Enable = 'off';
            app.CoordenadaEsteEditFieldLabel.Text = 'Coordenada Este';
            app.CoordenadaEste = uieditfield(app.MetadataPanel, 'numeric');
            app.CoordenadaEste.Enable = 'off';
            app.CoordenadaEste.Value = -100;
            app.CoordenadaNorteEditFieldLabel = uilabel(app.MetadataPanel);
            app.CoordenadaNorteEditFieldLabel.Enable = 'off';
            app.CoordenadaNorteEditFieldLabel.Text = 'Coordenada Norte';
            app.CoordenadaNorte = uieditfield(app.MetadataPanel, 'numeric');
            app.CoordenadaNorte.Enable = 'off';
            app.CoordenadaNorte.Value = -100;
            app.ZonaDropDownLabel = uilabel(app.MetadataPanel);
            app.ZonaDropDownLabel.Enable = 'off';
            app.ZonaDropDownLabel.Text = 'Zona';
            app.Zona = uidropdown(app.MetadataPanel);
            app.Zona.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60'};
            app.Zona.Enable = 'off';
            app.Zona.Value = '19';
            app.NS = uidropdown(app.MetadataPanel);
            app.NS.Items = {'N', 'S'};
            app.NS.Enable = 'off';
            app.NS.Value = 'S';
            app.PresionDropDownLabel = uilabel(app.ImportaDatpsADCPUIFigure);
            app.PresionDropDownLabel.Text = 'Presion';
            app.PresionDropDown = uidropdown(app.ImportaDatpsADCPUIFigure);
            app.PresionDropDown.Items = {'ADCP', 'RBR Solo', 'HOBO', 'Archivo Surface'};
            app.PresionDropDown.ValueChangedFcn = createCallbackFcn(app, @PresionDropDownValueChanged, true);
            app.PresionDropDown.Value = 'ADCP';
            app.ArchivoPresionEditFieldLabel = uilabel(app.ImportaDatpsADCPUIFigure);
            app.ArchivoPresionEditFieldLabel.Enable = 'off';
            app.ArchivoPresionEditFieldLabel.Text = 'Archivo Presion';
            app.ArchivoPresion = uieditfield(app.ImportaDatpsADCPUIFigure, 'text');
            app.ArchivoPresion.Enable = 'off';
            app.Importar = uibutton(app.ImportaDatpsADCPUIFigure, 'push');
            app.Importar.ButtonPushedFcn = createCallbackFcn(app, @ImportarButtonPushed, true);
            app.Importar.Text = 'Importar';
            app.Cerrar = uibutton(app.ImportaDatpsADCPUIFigure, 'push');
            app.Cerrar.ButtonPushedFcn = createCallbackFcn(app, @CerrarPushed, true);
            app.Cerrar.Text = 'Cerrar';
            app.NetCDFEditFieldLabel = uilabel(app.ImportaDatpsADCPUIFigure);
            app.NetCDFEditFieldLabel.Text = 'NetCDF';
            app.NetCDF = uieditfield(app.ImportaDatpsADCPUIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = ADCP_Importa

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportaDatpsADCPUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportaDatpsADCPUIFigure)
        end
    end
end