classdef ADCP_Procesa < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    ProceaDatosADCPUIFigure    matlab.ui.Figure
    ArchivoButton              matlab.ui.control.Button
    Profundidad                matlab.ui.control.UIAxes
    Archivo                    matlab.ui.control.EditField
    Temperatura                matlab.ui.control.UIAxes
    FechaInicioDropDownLabel   matlab.ui.control.Label
    FechaInicio                matlab.ui.control.DropDown
    FechaTerminoLabel          matlab.ui.control.Label
    FechaTermino               matlab.ui.control.DropDown
    Rangos                     matlab.ui.control.Table
    LimitesEjesLabel           matlab.ui.control.Label
    ProcesarButton             matlab.ui.control.Button
    InformacinDisponiblePanel  matlab.ui.container.Panel
    ConfiguracinCheckBox       matlab.ui.control.CheckBox
    SensoresCheckBox           matlab.ui.control.CheckBox
    ColumnaCheckBox            matlab.ui.control.CheckBox
    SuperficieCheckBox         matlab.ui.control.CheckBox
    Columna2CheckBox           matlab.ui.control.CheckBox
    Columna3CheckBox           matlab.ui.control.CheckBox
    OleajeCheckBox             matlab.ui.control.CheckBox
    NavegancinCheckBox         matlab.ui.control.CheckBox
    Mensajes                   matlab.ui.control.ListBox
    HusoHorarioArchivoDropDownLabel  matlab.ui.control.Label
    HusoHorario                matlab.ui.control.DropDown
    UsarDropDownLabel          matlab.ui.control.Label
    Usar                       matlab.ui.control.DropDown
    CerrarButton               matlab.ui.control.Button
  end

  
  properties (Access = private)
    np=0 % Description
  end
  
  methods (Access = private)
    
    function guarda_resultados(app,RES)
      %almacena los resultados en archivos excel y grnera figuras grapher y
      %surfer
      o1=RES.Fon;
      o2=RES.Sup;
      if RES.Sup<RES.Fon
        o1=RES.Sup;
        o2=RES.Fon;
      end
      Fecha=exceltime(datetime(datevec(RES.Fecha(RES.Sel))));
      app.mensaje('Guardando los resultados');
      %      refreshdata(handles.figure1); drawnow update
      
      % Almacena los datos
      [cam,nom,~]=fileparts(app.Archivo.Value);
      
      %constantes
      periodo=[.125 .25 .5 1 2 3 4 5]'*24; %en horas
      % Almacena en archivo excel los datos
      warning off MATLAB:xlswrite:AddSheet
      
      %
      % Hoja UV
      %
      txt='Guardando Componentes ...';
      app.mensaje(txt);
      clear T
      xls=fullfile(cam,[nom ' UV.xlsx']);
      if exist(xls,'file')
        delete(xls)
      end
      hoja='uv';
      T=table;
      T.Fecha=Fecha;
      T.U= RES.U(RES.Sel,o1:o2);
      T.V= RES.V(RES.Sel,o1:o2);
      writetable(T,xls,'Sheet',hoja);
      xls_delete_sheets(xls);
      app.np=app.np-1;
      app.mensaje([txt ' Listo']);
      %
      % Hoja Magdir
      %
      txt='Guardando Magnitudes y Direcciones ...';
      app.mensaje(txt);
      T=table;
      xls=fullfile(cam,[nom ' MagDir.xlsx']);
      if exist(xls,'file')
        delete(xls)
      end
      hoja='magdir';
      T.Fecha=Fecha;
      T.Y=zeros(size(Fecha));
      T.D= RES.Dir(RES.Sel,o1:o2);
      T.M= RES.Mag(RES.Sel,o1:o2);
      writetable(T,xls,'Sheet',hoja);
      %      np=np-1;
      xls_delete_sheets(xls);
      %        pos_men([txt ' Listo']);
      %
      % Hoja dvp
      %
      xls=fullfile(cam,[nom ' dvp.xlsx']);
      if exist(xls,'file')
        delete(xls)
      end
      hoja='dvp';
      txt='Guardando DVP ...';
      app.mensaje(txt);
      T=table;
      T.X= RES.x;
      T.Y= RES.y;
      writetable(T,xls,'Sheet',hoja);
      %      np=np-1;
      xls_delete_sheets(xls);
      %        pos_men([txt ' Listo']);
      %
      % Hoja capas
      %
      xls=fullfile(cam,[nom ' Capas.xlsx']);
      if exist(xls,'file')
        delete(xls)
      end
      txt='Guardando Informacion Capas ...';
      %        pos_men(txt);
      hoja='capas';
      Var={'' 'Distancia al Transductor (m)' '' '' 'Profundidad (m)' '' '';...
        'capa' 'media' 'Inicio' 'Termino' 'Media' 'Inicio' 'Termino'};
      xlswrite(xls,Var,hoja,'a2')
      xlswrite(xls,RES.capainfo,hoja,'a3')
      xlswrite(xls,{'Pres Min';'Pres Max';'Pres Promedio';'Pres Rango';...
        'NRS';'';'Fondo';'Intermedia';'Superficie'},hoja,'i3')
      p=RES.Prof(RES.Sel);
      xlswrite(xls,[nanmin(p);nanmax(p);nanmean(p);nanmax(p)-nanmin(p);...
        RES.NRS;NaN;RES.Fon;RES.Int;RES.Sup],hoja,'j3')
      xls_delete_sheets(xls);
      app.np=app.np-1;
      app.mensaje([txt ' Listo']);
      %
      % Hoja est_mag
      %
      txt='Guardando Estadistica ...';
      app.mensaje(txt);
      xls=fullfile(cam,[nom ' Estadistica.xlsx']);
      if exist(xls,'file')
        delete(xls)
      end
      hoja='est_mag';
      Var={'' 'Profundidad' 'N' 'Magnitud [m/s]' '' '' '' ...
        'Coeficiente' 'Magnitud (nudos)' ''; 'capa' '[m]' '' 'Minimo' ...
        'Promedio' 'Maximo' 'Des. Est.' 'Variacion' 'Promedio' 'Maximo'};
      xlswrite(xls,Var,hoja,'A2')
      xlswrite(xls,RES.estmag,hoja,'a4')
      xls_delete_sheets(xls);
      app.np=app.np-1;
      app.mensaje([txt ' Listo']);
      %
      % Hoja Neto
      %
      txt='Guardando Flujos Netos ...';
      app.mensaje(txt);
      xls=fullfile(cam,[nom ' Flujos.xlsx']);
      if exist(xls,'file')
        delete(xls)
      end
      hoja='neto';
      Var={'capa' 'Profundidad' 'Dir' '' 'Magnitud' 'Distancia' 'Mag_Rel' ...
        'Este' 'Norte'; '' '[m]' '[grados]' '' '[m/s]' '[m]' '' '[km]' '[km]'};
      xlswrite(xls,Var,hoja,'a1')
      xlswrite(xls,RES.neto(:,1:3),hoja,'a3');
      xlswrite(xls,RES.ssd,hoja,'d3');
      xlswrite(xls,[RES.neto(o1:o2,4:5) RES.neto(o1:o2,4)/max(RES.neto(o1:o2,4)) ...
        ones(o2,1)*RES.Este/1000 ones(o2,1)*RES.Norte/1000],...
        hoja,'e3');
      app.np=app.np-1;
      app.mensaje([txt ' Listo']);
      %
      % Hoja Frecuencia Magnitud
      %
      txt='Guardando Frecuencias Magnitudes ...';
      app.mensaje(txt);
      xls=fullfile(cam,[nom ' Frecuencias.xlsx']);
      if exist(xls,'file')
        delete(xls)
      end
      hoja='frec_mag';
      Var={'' 'Rango de  magnitud [cm/s]' '' '' '' '' '' '' '' '';...
        'capa' '<1,5' '1,5 - 3,0' '3,0 - 5,0' '5,0 - 10,0' ...
        '10,0 - 15,0' '15,0 - 20,0' '20,0 - 25,0' '25,0 - 30,0' '>30.0'};
      xlswrite(xls,Var,hoja,'A1')
      xlswrite(xls,RES.capainfo(:,1),hoja,'a3')
      xlswrite(xls,RES.FrecMag,hoja,'b3')
      app.np=app.np-1;
      app.mensaje([txt ' Listo']);
      %
      % Hoja Frecuencia Direccion
      %
      txt='Guardando Frecuencias Direcciones ...';
      app.mensaje(txt);
      hoja='frec_dir';
      Var={'' 'Direcion' '' '' '' '' '' '' '';'capa' 'N' 'NE' 'E' 'SE' 'S' ...
        'SW' 'W' 'NW'};
      xlswrite(xls,Var,hoja,'a1')
      xlswrite(xls,RES.capainfo(:,1),hoja,'a3')
      xlswrite(xls,RES.FrecDir,hoja,'b3')
      %
      % Etiquetas
      %
      hoja='Etiquetas';
      xlswrite(xls,{'dir' 'texto'},hoja,'a1')
      xlswrite(xls,(0:22.5:337.5)',hoja,'a2')
      xlswrite(xls,{'N';'NNE';'NE';'ENE';'E';'ESE';'SE';'SSE';'S';'SSW';...
        'SW';'WSW';'W';'WNW';'NW';'NNW'},hoja,'b2');
      app.np=app.np-1;
      app.mensaje([txt ' Listo']);
      xls_delete_sheets(xls);
      %
      % Espectros
      %
      xls=fullfile(cam,[nom ' Espectros.xlsx']);
      if exist(xls,'file')
        delete(xls)
      end
      if RES.DT
        for j=1:o2
          txt=['Guardando Espectros Capa ' num2str(j) ' ...'];
          app.mensaje(txt);
          hoja=['Espectro ' num2str(j,'%2.2i')];
          xlswrite(xls,{'Frecuencia','U PSD','V PSD', ...
            'Cross Spectral Density','Trans','Coherence','Pxxc','Pyyc','Pxyc',...
            'Frec (1/Hr)','Max_PSD','Periodo (Hr)' 'Frec' 'LC' 'Simbolo' ...
            'Texto'},hoja,'A1')
          xlswrite(xls,RES.P{j},hoja,'a2')
          pmax=fix(log10(max(max(RES.P{j}(:,2:3)))))+1;
          xlswrite(xls,[1./periodo 10^pmax.*ones(size(periodo)) periodo]...
            ,hoja,'j2')
          ff=NaN(3,1);LC=ff;
          frec=1./periodo;
          jj=find(frec>=min(RES.P{j}(:,1)));
          ff(:)=1./periodo(jj(1));
          LC(2)=max(nanmax(RES.P{j}(:,2:3)));
          L = log10([RES.nce{j}]);
          L=(L-diff(L))*LC(2);
          LC(1)=LC(2)-L(1);LC(3)=LC(2)+L(2);
          xlswrite(xls,[ff LC [63;10;63]],hoja,'M2');
          xlswrite(xls,{'95%'},hoja,'P3');
          app.np=app.np-1;
          app.mensaje([txt ' Listo']);
        end
        xls_delete_sheets(xls);
      end
      app.mensaje('Resultados Guardados');
    end
    
    function gen_grapher(app,RES)
      %==========================================================================
      %
      %  Generacion de las figuras de los analisis de ADCP en Grapher
      %
      % Autor:
      %     Sergio A. Rosales
      %
      % Control de Actualizaciones
      % 02-Ene-2018:
      %
      %==========================================================================
      nc=app.Archivo.Value;
      Instalado=ncreadatt(nc,'/Configuracion/','Instalado');
      Fondeo_Superficie=~contains(Instalado,{'Fondo' 'Media Agua'});


      o1=RES.Fon;
      o2=RES.Sup;
      if RES.Sup<RES.Fon
        o1=RES.Sup;
        o2=RES.Fon;
      end
      Sup=RES.Sup;
      app.mensaje('Generando Archivos Grapher');
      %
      % Definicion de Variables
      %
      [cp,~,~]=fileparts(mfilename('fullpath'));
      [cam,nom,~]=fileparts(app.Archivo.Value);
      arcs={'Fondo' 'Intermedia' 'Superficie'};
      f=RES.Fecha(RES.Sel);
      df=f(end)-f(1);
      NA=length(unique(year(f)));
      if df<=2
        Mayor=3;
        MayorF=4;
        Menor=4;
        MenorF=11;
        Prefijo=['00:00' char(13)];
      elseif isequal(NA,1)
        Mayor=1;
        MayorF=7;
        Menor=3;
        MenorF=23;
        Prefijo=['1' char(13)];
      else
        Mayor=0;
        MayorF=14;
        Menor=1;
        MenorF=19;
        Prefijo=['1' char(13)];
      end
      %
      % Redefine Capa superior si ni/N<90%
      %
      N=sum(RES.Sel);
      P=RES.estmag(:,3)/N*100;
      if P(Sup)<80
        for j=o2:-1:o1
          if P(j)>=80
            Sup=j;
            break
          end
        end
      end
      %
      % obtencion de limites
      %
      capa=[RES.Fon RES.Int Sup];
      velmax=max([abs(min(nanmin(RES.U(RES.Sel,capa)))) ....
        abs(max(nanmax(RES.U(RES.Sel,capa)))) ...
        abs(min(nanmin(RES.V(RES.Sel,capa)))) ...
        abs(max(nanmax(RES.V(RES.Sel,capa))))]);
      velmin=-velmax;
      xymax=max([max(nanmax(RES.x(:,capa))) max(nanmax(RES.y(:,capa)))]);
      xymin=min([min(nanmin(RES.x(:,capa))) min(nanmin(RES.y(:,capa)))]);
      %
      % figura marea
      %
      Fini=exceltime(datetime(datevec(f(1))));
      Fend=exceltime(datetime(datevec(f(end))));
      txt='Graficando Marea ...';
      app.mensaje(txt);
      arc='marea corr';
      xls=fullfile(cam,[nom ' Series.xlsx']);
      hoja='Series';
      T=readtable(xls,'sheet',hoja);
      Var=fieldnames(T);
      columna=2;
      if any(contains(Var,'Prof'))
        columna=find(strcmpi(Var,'Prof'));
        DS=nanstd(T.Prof);
      elseif any(contains(Var,'Depth'))
        columna=find(contains(Var,'Depth'));
        DS=nanstd(T.Depth);
      elseif contains(Var,'Pression')
        columna=find(contains(Var,'Pression'));
        DS=nanstd(T.Pression);
      end
      if DS>0
        Plantilla=fullfile(cp,'Plantillas',[arc '.grt']);
        GRF=fullfile(cam,[arc '.grf']);
        grf=actxserver('grapher.application');
        grf.set('Visible',true);
        D=get(grf,'Documents');
        Doc=D.invoke('Open',Plantilla,[xls '!' hoja]);
        Shapes=Doc.invoke('Shapes');
        Plot=Shapes.invoke('Item',1);
        % Asigan columna donde esta la profundidad
        G=Plot.invoke('Plots');
        GL=G.invoke('Item','Line/Scatter Plot 1');
        GL.set('yCol',columna);
        % Configura Eje X
        Ejes=Plot.invoke('Axes');
        EjeX=Ejes.invoke('Item','X Axis 1');
        EjeX.set('AutoMin',false);
        EjeX.set('AutoMax',false);
        EjeX.set('Max',Fend);
        EjeX.set('Min',Fini);
        Xtick=EjeX.invoke('TickMarks');
        Xtick.set('StartAtAuto',true);
        Xtick.set('EnableDTSpacing',true);
        Xtick.set('DTUnits',Mayor);
        Xtick.set('DTSpacing',1);
        Xtick.set('EnableDTMinorSpacing',true);
        Xtick.set('DTMinorUnits',Menor);
        Xtick.set('DTMinorSpacing',1);
        XLabels=EjeX.invoke('TickLabels');
        XLabels.set('MajorOn',true);
        XLabels.set('LimitWidth',false);
        XLMa=XLabels.invoke('MajorFormat');
        XLMa.set('DatetimeFormat',MayorF);
        XLMa.set('Prefix',Prefijo);
        LL=XLMa.invoke('PrefixMT');
        LL.set('size',8);
        LL.set('Justification',1);
        XLabels.set('MinorOn',true);
        XLMi=XLabels.invoke('MinorFormat');
        XLMi.set('DateTimeFormat',MenorF);
%        XLMi.set('Postfix','');
        Xtick.set('StartAtAuto',true);
        % Guarda Figura y Cierra
        Doc.invoke('SaveAs',GRF);
        Doc.invoke('Close',1);
        grf.invoke('Quit');
        grf.release;
        clear grf D Doc Shapes Plot Ejes EjeX Xtick XLabels XLMa XLMi
      end
      app.np=app.np-1;
      app.mensaje([txt ' Listo']);
      %
      % figura componentes
      %
      txt='Graficando Componentes';
      app.mensaje(txt);
      arc='componentes';
      xls=fullfile(cam,[nom ' UV.xlsx']);
      hoja='uv';
      GRF=fullfile(cam,[arc '.grf']);
      Plantilla=fullfile(cp,'Plantillas',[arc '.grt']);
      grf=actxserver('grapher.application');
      grf.set('Visible',true);
      D=get(grf,'Documents');
      Doc=D.invoke('Open',Plantilla,[xls '!' hoja]);
      Shapes=Doc.invoke('Shapes');
      Plot=Shapes.invoke('Item',1);
      %Configura Eje X
      Ejes=Plot.invoke('Axes');
      EjeX=Ejes.invoke('Item','X Axis 1');
      EjeX.set('AutoMin',false);
      EjeX.set('AutoMax',false);
      EjeX.set('Max',Fend);
      EjeX.set('Min',Fini);
      Xtick=EjeX.invoke('TickMarks');
      Xtick.set('StartAtAuto',true);
      Xtick.set('EnableDTSpacing',true);
      Xtick.set('DTUnits',Mayor);
      Xtick.set('DTSpacing',1);
      Xtick.set('EnableDTMinorSpacing',true);
      Xtick.set('DTMinorUnits',Menor);
      Xtick.set('DTMinorSpacing',1);
      XLabels=EjeX.invoke('TickLabels');
      XLabels.set('LimitWidth',false);
      XLabels.set('MajorOn',true);
      XLMa=XLabels.invoke('MajorFormat');
      XLMa.set('DatetimeFormat',MayorF);
      XLMa.set('Prefix',Prefijo);
      LL=XLMa.invoke('PrefixMT');
      LL.set('size',8);
      LL.set('Justification',1);
      XLabels.set('MinorOn',true);
      XLMi=XLabels.invoke('MinorFormat');
      XLMi.set('DateTimeFormat',MenorF);
%      XLMi.set('Postfix','');
      % Configura Curvas
      P=Plot.invoke('Plots');
      U=P.invoke('Item','Fondo');
      U.set('xCol',1);
      U.set('yCol',RES.Fon+1);
      U=P.invoke('Item','Intermedia');
      U.set('xCol',1);
      U.set('yCol',RES.Int+1);
      U=P.invoke('Item','Superficie');
      U.set('xCol',1);
      U.set('yCol',Sup+1);
      U=P.invoke('Item','Fondo-v');
      U.set('xCol',1);
      U.set('yCol',RES.Fon+o2+1);
      U=P.invoke('Item','Intermedia-v');
      U.set('xCol',1);
      U.set('yCol',RES.Int+o2+1);
      U=P.invoke('Item','Superficie-v');
      U.set('xCol',1);
      U.set('yCol',Sup+o2+1);
      %Configura Eje Y
      EjeY=Ejes.invoke('Item','Y Axis 1');
      EjeY.set('AutoMin',false);
      EjeY.set('AutoMax',false);
      EjeY.set('Max',velmax);
      EjeY.set('Min',velmin);
      EjeY=Ejes.invoke('Item','Y Axis 2');
      EjeY.set('AutoMin',false);
      EjeY.set('AutoMax',false);
      EjeY.set('Max',velmax);
      EjeY.set('Min',velmin);
      % Guarda Figura y Cierra
      Doc.invoke('SaveAs',GRF);
      Doc.invoke('Close',1);
      grf.invoke('Quit');
      grf.release;
      clear grf D Doc Shapes Plot Ejes EjeX Xtick XLabels XLMa XLMi EjeY P U
      app.mensaje([txt ' Listo']);
      %
      % figura chascones
      %
      txt='Graficando Chascones';
      app.mensaje(txt);
      arc='chascones';
      xls=fullfile(cam,[nom ' MagDir.xlsx']);
      hoja='magdir';
      Plantilla=fullfile(cp,'Plantillas',[arc '.grt']);
      GRF=fullfile(cam,[arc '.grf']);
      grf=actxserver('grapher.application');
      grf.set('Visible',true);
      D=get(grf,'Documents');
      Doc=D.invoke('Open',Plantilla,[xls '!' hoja]);
      Shapes=Doc.invoke('Shapes');
      Plot=Shapes.invoke('Item',1);
      %Configura Eje X
      Ejes=Plot.invoke('Axes');
      EjeX=Ejes.invoke('Item','X Axis 1');
      EjeX.set('AutoMin',false);
      EjeX.set('AutoMax',false);
      EjeX.set('Max',Fend);
      EjeX.set('Min',Fini);
      Xtick=EjeX.invoke('TickMarks');
      Xtick.set('StartAtAuto',true);
      Xtick.set('EnableDTSpacing',true);
      Xtick.set('DTUnits',Mayor);
      Xtick.set('DTSpacing',1);
      Xtick.set('EnableDTMinorSpacing',true);
      Xtick.set('DTMinorUnits',Menor);
      Xtick.set('DTMinorSpacing',1);
      XLabels=EjeX.invoke('TickLabels');
      XLabels.set('MajorOn',true);
      XLabels.set('LimitWidth',false);
      XLMa=XLabels.invoke('MajorFormat');
      XLMa.set('DatetimeFormat',MayorF);
      XLMa.set('Prefix',Prefijo);
      LL=XLMa.invoke('PrefixMT');
      LL.set('size',8);
      LL.set('Justification',1);
      XLabels.set('MinorOn',true);
      XLMi=XLabels.invoke('MinorFormat');
      XLMi.set('DateTimeFormat',MenorF);
%      XLMi.set('Postfix','');
      % Cchascon capas
      P=Plot.invoke('Plots');
      U=P.invoke('Item','Fondo');
      U.set('xCol',1);
      U.set('yCol',2);
      U.set('AngleCol',RES.Fon+2);
      U.set('MagnitudeCol',RES.Fon+o2+2);
      U=P.invoke('Item','Intermedia');
      U.set('xCol',1);
      U.set('yCol',2);
      U.set('AngleCol',RES.Int+2);
      U.set('MagnitudeCol',RES.Int+o2+2);
      U=P.invoke('Item','Superficie');
      U.set('xCol',1);
      U.set('yCol',2);
      U.set('AngleCol',Sup+2);
      U.set('MagnitudeCol',Sup+o2+2);
      % Guarda Figura y Cierra
      Doc.invoke('SaveAs',GRF);
      Doc.invoke('Close',1);
      grf.invoke('Quit');
      grf.release;
      clear grf D Doc Shapes Plot Ejes EjeX Xtick XLabels XLMa XLMi EjeY P U
      app.np=app.np-1;
      app.mensaje([txt ' Listo']);
      %
      % figura DVP
      %
      txt='Graficando DVP';
      app.mensaje(txt);
      arc='dvp';
      xls=fullfile(cam,[nom ' dvp.xlsx']);
      hoja='dvp';
      Plantilla=fullfile(cp,'Plantillas',[arc '.grt']);
      GRF=fullfile(cam,[arc '.grf']);
      grf=actxserver('grapher.application');
      grf.set('Visible',true);
      D=get(grf,'Documents');
      Doc=D.invoke('Open',Plantilla,[xls '!' hoja]);
      Shapes=Doc.invoke('Shapes');
      Plot=Shapes.invoke('Item',1);
      P=Plot.invoke('Plots');
      U=P.invoke('Item','Fondo');
      U.set('xCol',RES.Fon);
      U.set('yCol',RES.Fon+o2);
      U=P.invoke('Item','Intermedia');
      U.set('xCol',RES.Int);
      U.set('yCol',RES.Int+o2);
      U=P.invoke('Item','Superficie');
      U.set('xCol',Sup);
      U.set('yCol',Sup+o2);
      Ejes=Plot.invoke('Axes');
      EjeX=Ejes.invoke('Item','X Axis 1');
      EjeX.set('AutoMin',false);
      EjeX.set('AutoMax',false);
      EjeX.set('Max',xymax);
      EjeX.set('Min',xymin);
      EjeY=Ejes.invoke('Item','Y Axis 1');
      EjeY.set('AutoMin',false);
      EjeY.set('AutoMax',false);
      EjeY.set('Max',xymax);
      EjeY.set('Min',xymin);
      % Guarda Figura y Cierra
      Doc.invoke('SaveAs',GRF);
      Doc.invoke('Close',1);
      grf.invoke('Quit');
      grf.release;
      clear grf D Doc Shapes Plot Ejes EjeX Xtick XLabels XLMa XLMi EjeY P U
      app.np=app.np-1;app.mensaje([txt ' Listo']);
      %
      % figura frecuencias
      %
      txt='Graficando Frecuencias';
      app.mensaje(txt);
      Dire={'N' 'NE' 'E' 'SE' 'S' 'SW' 'W' 'NW'};ndir=length(Dire);
      xls=fullfile(cam,[nom ' Frecuencias.xlsx']);
      arc='frecuencias';
      hoja1='frec_mag';
      hoja2='frec_dir';
      Plantilla=fullfile(cp,'Plantillas',[arc '.grt']);
      GRF=fullfile(cam,[arc '.grf']);
      grf=actxserver('grapher.application');
      grf.set('Visible',true);
      D=get(grf,'Documents');
      Doc=D.invoke('Open',Plantilla,[xls '!' hoja1]);
      Shapes=Doc.invoke('Shapes');
      Plot=Shapes.invoke('Item','Frec Mag');
      P=Plot.invoke('Plots');
      U=P.invoke('Item','> 30');
      U.set('LastRow',o2+2);
      Ejes=Plot.invoke('Axes');
      EjeX=Ejes.invoke('Item','X Axis 1');
      EjeX.set('AutoMax',false);
      EjeX.set('Max',o2+.5);
      Plot=Shapes.invoke('Item','Frec Dir');
      P=Plot.invoke('Plots');
      for j=1:ndir
        U=P.invoke('Item',Dire{j});
        U.set('worksheet',[xls '!' hoja2]);
      end
      U=P.invoke('Item','N');
      U.set('LastRow',o2+2);
      Ejes=Plot.invoke('Axes');
      EjeX=Ejes.invoke('Item','X Axis 1');
      EjeX.set('AutoMax',false);
      EjeX.set('Max',o2+.5);
      % Guarda Figura y Cierra
      Doc.invoke('SaveAs',GRF);
      Doc.invoke('Close',1);
      grf.invoke('Quit');
      grf.release;
      clear grf D Doc Shapes Plot Ejes EjeX P U
      app.np=app.np-1;app.mensaje([txt ' Listo']);
      %
      % figura Rosa Corrientes
      %
      arc='rosa';
      xls=fullfile(cam,[nom ' MagDir.xlsx']);
      hoja='magdir';
      Plantilla=fullfile(cp,'Plantillas',[arc '.grt']);
      xls2=fullfile(cam,[nom ' Frecuencias.xlsx']);
      for j=1:3
        txt=['Graficando Rosa de Corrientes Capa: ' arcs{j}];
        app.mensaje(txt);
        GRF=fullfile(cam,[arc ' ' arcs{j} '.grf']);
        grf=actxserver('grapher.application');
        grf.set('Visible',true);
        D=get(grf,'Documents');
        Doc=D.invoke('Open',Plantilla,[xls '!' hoja]);
        Shapes=Doc.invoke('Shapes');
        Plot=Shapes.invoke('Item',1);
        Ejes=Plot.invoke('Axes');
        EjeX=Ejes.invoke('Item','Angle Axis 1');
        XLabels=EjeX.invoke('TickLabels');
        L=Plot.invoke('Legends');
        ll=L.invoke('Item',1);
        lt=ll.invoke('Title');
        P=Plot.invoke('Plots');
        U=P.invoke('Item','Rosa');
        XLabels.set('worksheet',[ xls2 '!Etiquetas']);
        lt.set('text',['Capa ' arcs{j} char(13) 'Magnitud' char(13) '[cm/s]']);
        U.set('dataCol',capa(j)+2);
        U.set('speedCol',capa(j)+o2+2);
        Doc.invoke('SaveAs',GRF);
        Doc.invoke('Close',1);
        grf.invoke('Quit');
        grf.release;
        clear grf D Doc Shapes Plot Ejes EjeX P U L lt ll
        app.np=app.np-1;app.mensaje([txt ' Listo']);
      end
      %
      % figura Flujos Netos
      %
      txt='Graficando Flujos Netos';
      app.mensaje(txt);
      xls=fullfile(cam,[nom ' Flujos.xlsx']);
      xls2=fullfile(cam,[nom ' Frecuencias.xlsx']);
      hoja='neto';
      arc='neto';
      Plantilla=fullfile(cp,'Plantillas',[arc '.grt']);
      GRF=fullfile(cam,[arc '.grf']);
      grf=actxserver('grapher.application');
      grf.set('Visible',true);
      D=get(grf,'Documents');
      Doc=D.invoke('Open',Plantilla,[xls '!' hoja]);
      Shapes=Doc.invoke('Shapes');
      Plot=Shapes.invoke('Item','Todas');
      P=Plot.invoke('Plots');
      U=P.invoke('Item','Todas');
      U.set('AutoFirstRow',false);
      U.set('AutoLastRow',false);
      if RES.Sup>RES.Fon
        U.set('FirstRow',RES.Fon+2);
        U.set('LastRow',RES.Sup+2);
      else
        U.set('FirstRow',RES.Sup+2);
        U.set('LastRow',RES.Fon+2);
      end
      clear U;
      Ejes=Plot.invoke('Axes');
      EjeX=Ejes.invoke('Item','Angle Axis 1');
      XLabels=EjeX.invoke('TickLabels');
      XLabels.set('worksheet',[ xls2 '!Etiquetas']);
      
      Plot=Shapes.invoke('Item','FIS');
      Ejes=Plot.invoke('Axes');
      EjeX=Ejes.invoke('Item','Angle Axis 1');
      XLabels=EjeX.invoke('TickLabels');
      XLabels.set('worksheet',[ xls2 '!Etiquetas']);
      P=Plot.invoke('Plots');
      U=P.invoke('Item','Fondo');
      ultimo=U.get('LastRow');
      U.set('AutoFirstRow',false);
      U.set('AutoLastRow',false);
      if RES.Fon>ultimo
        U.set('LastRow',RES.Fon+2);
        U.set('FirstRow',RES.Fon+2);
      else
        U.set('FirstRow',RES.Fon+2);
        U.set('LastRow',RES.Fon+2);
      end
      clear U;
      U=P.invoke('Item','Intermedia');
      primero=U.get('FirstRow');
      U.set('AutoFirstRow',false);
      U.set('AutoLastRow',false);
      if RES.Int<primero
        U.set('FirstRow',RES.Int+2);
        U.set('LastRow',RES.Int+2);
      else
        U.set('LastRow',RES.Int+2);
        U.set('FirstRow',RES.Int+2);
      end
      clear U
      U=P.invoke('Item','Superficie');
      primero=U.get('FirstRow');
      U.set('AutoFirstRow',false);
      U.set('AutoLastRow',false);
      if RES.Sup<primero
        U.set('FirstRow',Sup+2);
        U.set('LastRow',Sup+2);
      else
        U.set('LastRow',Sup+2);
        U.set('FirstRow',Sup+2);
      end
      Doc.invoke('SaveAs',GRF);
      Doc.invoke('Close',1);
      grf.invoke('Quit');
      grf.release;
      clear grf D Doc Shapes Plot Ejes EjeX P U L lt ll
      app.np=app.np-1;app.mensaje([txt ' Listo']);
      %
      % figura Espectros
      %
      if RES.DT
        arc='espectro';
        xls=fullfile(cam,[nom ' Espectros.xlsx']);
        Plantilla=fullfile(cp,'Plantillas',[arc '.grt']);
        for j=1:3
          hoja=['Espectro ' num2str(capa(j),'%2.2i')];
          txt=['Graficando Especttro Capa: ' arcs{j}];
          app.mensaje(txt);
          xmin=min(RES.P{capa(j)}(:,1));
          xmax=max(RES.P{capa(j)}(:,1));
          GRF=fullfile(cam,[arc ' ' arcs{j} '.grf']);
          grf=actxserver('grapher.application');
          grf.set('Visible',true);
          D=get(grf,'Documents');
          Doc=D.invoke('Open',Plantilla,[xls '!' hoja]);
          Shapes=Doc.invoke('Shapes');
          Plot=Shapes.invoke('Item',1);
          Titulo=Plot.invoke('Title');
          Titulo.set('Text',['Capa de ' arcs{j}]);
          Ejes=Plot.invoke('Axes');
          EjeX=Ejes.invoke('Item','X Axis 1');
          EjeX.set('AutoMin',false);
          EjeX.set('AutoMax',false);
          if xmin<EjeX.get('Min')
            EjeX.set('Min',xmin);
            EjeX.set('Max',xmax);
          else
            EjeX.set('Max',xmax);
            EjeX.set('Min',xmin);
          end
          EjeX=Ejes.invoke('Item','X Axis 2');
          EjeX.set('AutoMin',false);
          EjeX.set('AutoMax',false);
          if xmin<EjeX.get('Min')
            EjeX.set('Min',xmin);
            EjeX.set('Max',xmax);
          else
            EjeX.set('Max',xmax);
            EjeX.set('Min',xmin);
          end
          Doc.invoke('SaveAs',GRF);
          Doc.invoke('Close',1);
          grf.invoke('Quit');
          grf.release;
          clear grf D Doc Shapes Plot Ejes EjeX P U L lt ll
          app.np=app.np-1;app.mensaje([txt ' Listo']);
        end
      end
      %
      % Grilla Magnitud tiempo v/s profundidad
      %
      txt='Graficando Magnitud v/s tiempo';
      app.mensaje(txt);
      BLN=fullfile(cam,[nom ' marea.bln']);
      fid=fopen(BLN,'wt');
      fprintf(fid,'%i %i\n',N,0);
      for j=1:N
        fprintf(fid,'%f %f\n',T.Fecha(j),T.(Var{columna})(j));
      end
      fclose(fid);
      m=RES.Mag(RES.Sel,:)'*100;
      z=RES.Prof(RES.Sel)';
      [~,nc]=size(m);
      if ~Fondeo_Superficie
        for j=1:nc
          jj=RES.Capas>=z(j);
          m(jj,j)=NaN;
        end
        jj=find(RES.Capas<=max(z));
        m=m(jj,:);
      else
        jj=true(size(RES.Capas));
      end
      [nf,nc]=size(m);
      archivo=fullfile(cam,[nom ' adcp_mag.grd']);
      fid=fopen(archivo,'wt');
      fprintf(fid,'%s\n','DSAA');
      fprintf(fid,'%i %i\n',nc,nf);
      fprintf(fid,'%f %f\n',Fini,Fend);
      fprintf(fid,'%f %f\n',min(RES.Capas(jj)),max(RES.Capas(jj)));
      fprintf(fid,'%i %i\n',min(nanmin(m)),max(nanmax(m)));
      jj=isnan(m);
      m(jj)=1.70141E+038;
      for jf=1:nf
        for jc=1:nc
          fprintf(fid,'%f ',m(jf,jc));
        end
        fprintf(fid,'\n');
      end
      fclose(fid);
      zmax=max(z);
      zmin=0;
      zint=5; %determinar
      if zmax/zint>10
        zint=10;
      end
      if mod(zmax,zint)>0
        zmax=(fix(zmax/zint)+1)*zint;
      end
      NA=length(unique(year(f)));
      if df<2
        dt=1/24;
        LF='HH:mm';
      elseif isequal(NA,1)
        dt=1;
        LF='dd/MM';
      else
        dt=1;
        LF='dd/MM/yy';
      end
      fv=datevec(f(1));
      if dt<1
        if f(1)>datenum(fv(1),fv(2),fv(3),fv(4),0,0)
          f1=datenum(fv(1),fv(2),fv(3),fv(4)+1,0,0);
        else
          f1=datenum(fv(1),fv(2),fv(3),fv(4),0,0);
        end
      else
        if f(1)>datenum(fv(1),fv(2),fv(3),0,0,0)
          f1=datenum(fv(1),fv(2),fv(3)+1,0,0,0);
        else
          f1=datenum(fv(1),fv(2),fv(3),0,0,0);
        end
      end
      f1=exceltime(datetime(datevec(f1)));
      fv=datevec(f(end));
      if dt<1
        if f(end)<datenum(fv(1),fv(2),fv(3),fv(4),0,0)
          f2=datenum(fv(1),fv(2),fv(3),fv(4)-1,0,0);
        else
          f2=datenum(fv(1),fv(2),fv(3),fv(4),0,0);
        end
      else
        if f(end)<datenum(fv(1),fv(2),fv(3),0,0,0)
          f2=datenum(fv(1),fv(2),fv(3)+1,0,0,0);
        else
          f2=datenum(fv(1),fv(2),fv(3),0,0,0);
        end
      end
      f2=exceltime(datetime(datevec(f2)));
      
      srf=actxserver('surfer.application');
      srf.Visible=true;
      Plot=srf.Documents.Add;
      Plot.PageSetup.Orientation=2;
      Shapes=Plot.Shapes;
      Map=Shapes.AddColorReliefMap(archivo);
      
      Map.SetLimits(Fini,Fend,zmin,zmax);
      Map.xLength=24;
      Map.yLength=5;
      [~,arc,ext]=fileparts(archivo);
      M=Map.Overlays.Item(['Color Relief-' arc ext]);
      M.MissingDataColorRGBA.Opacity=0;
      M.HillShading=false;
      M.ColorMap.SetDataLimits(0,30);
      M.ColorMap.LoadFile(fullfile(srf.Path,'ColorScales','Rainbow.clr'));
      M.ShowColorScale=true;
      Eje=Map.Axes.Item('Left Axis');
      Eje.SetScale(zmin,zmax,zint,zmin,zmax);
      Eje.Title='Distancia al Transductor [m]';
      Eje=Map.Axes.Item('Right Axis');
      Eje.SetScale(zmin,zmax,zint,zmin,zmax);
      
      Eje=Map.Axes.Item('Bottom Axis');
      Eje.LabelFormat.Type=5;
      Eje.LabelFormat.DateTimeFormat=LF;
      Eje.SetScale(Fini,Fend,dt,f1,f2);
      Eje=Map.Axes.Item('Top Axis');
      Eje.SetScale(Fini,Fend,dt,f1,f2);
      
      Super=Shapes.AddVectorBaseLayer(Map,BLN);
      Linea=Super.Line;
      Linea.Width=.05;
      CS=Shapes.Item('Color Scale');
      CS.Rotation=-90;
      CS.Left=Map.Left+Map.Width/2-CS.Width/2;
      CS.Top=Map.Top-Map.Height-CS.Height/4;
      CS.LabelAngle=90;
      CS.Title='Magnitud [cm/s]';
      CS.TitleOffsetHorizontal=6.5;
      Plot.SaveAs(fullfile(cam,[nom ' adcp_mag.srf']));
      Plot.Close;
      srf.Quit;
      srf.release;
      app.np=app.np-1;app.mensaje([txt ' Listo']);
      app.mensaje('Graficos Creados');
    end
    
    function mensaje(app,txt)
      app.np=app.np+1;
      app.Mensajes.Items{app.np}=txt;
      app.Mensajes.ItemsData(app.np)=app.np;
      scroll(app.Mensajes,txt);
      app.Mensajes.Value=app.np;
      drawnow
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Button pushed function: ArchivoButton
    function ArchivoButtonPushed(app, event)
      clc
      app.Mensajes.Items={''};app.Mensajes.ItemsData=1;
      app.np=0;
      [arc,cam]=uigetfile('*.nc','Selección del Archivo a Procesar');
      if isequal(arc,0)
        app.mensaje('No se selecciono archivo a procesar');
      else
        [nc,app.Archivo.Value]=deal(fullfile(cam,arc));
        I=ncinfo(nc);
        Grupo={I.Groups.Name}';
        for j=1:length(Grupo)
          switch Grupo{j}
            case 'Configuracion'
              app.ConfiguracinCheckBox.Value=true;
            case 'Sensores'
              app.SensoresCheckBox.Value=true;
            case 'Columna'
              app.ColumnaCheckBox.Value=true;
            case 'Columna2'
              app.Columna2CheckBox.Value=true;
            case 'Columna3'
              app.Columna3CheckBox.Valur=true;
            case 'Superficie'
              app.SuperficieCheckBox.Value=true;
            case 'Oleaje'
              app.OleajeCheckBox.Value=true;
            case 'Nevegacion'
              app.NavegancinCheckBox.Value=true;
          end
        end
        
        
        if any(contains(Grupo,'Sensores'))
          I=ncinfo(nc,'/Sensores/Fecha');
          att={I.Attributes.Name};
          jj=contains(att,'Huso Horario');
          if any(jj)
            [app.HusoHorario.Value,app.Usar.Value]=deal(I.Attributes(jj).Value);
          else
            [app.HusoHorario.Value,app.Usar.Value]=deal('Sin');
          end
          Fecha=ncread(nc,'/Sensores/Fecha');
          app.FechaInicio.Items=cellstr(datestr(Fecha,'dd-mm-yyyy HH:MM:SS'));
          app.FechaInicio.Value=datestr(Fecha(1),'dd-mm-yyyy HH:MM:SS');
          app.FechaTermino.Items=cellstr(datestr(Fecha,'dd-mm-yyyy HH:MM:SS'));
          app.FechaTermino.Value=datestr(Fecha(end),'dd-mm-yyyy HH:MM:SS');
          Sel=logical(ncread(nc,'/Sensores/Sel'));
          Fecha=Fecha(Sel);
          I=ncinfo(nc,'/Sensores/');
          Var={I.Variables.Name};
          jj=contains(Var,{'Depth' 'Prof'});
          if sum(jj)>1
            j1=strcmpi(Var,'Depth');
            j2=strcmpi(Var,'Prof');
            if isequal(sum(j1),1) && isequal(sum(j2),1)
              jj=j2;
            elseif isequal(j1,1)
              jj=j1;
            else
              jj=j2;
            end
          end
          if isequal(sum(jj),0)
            error('No se encontraron datos de Profundidad');
          end
          Prof=ncread(nc,['/Sensores/' Var{jj}]);
          Prof=Prof(Sel);
          jj=contains(Var,{'Temperature' 'Temperatura'});
          Temp=ncread(nc,['/Sensores/' Var{jj}]);
          Temp=Temp(Sel);
          plot(app.Profundidad,Fecha,Prof);
          datetick(app.Profundidad)
          plot(app.Temperatura,Fecha,Temp);
          datetick(app.Temperatura)
          if ~strcmp(app.HusoHorario.Value,'Sin')
            xlabel(app.Temperatura,app.HusoHorario.Value);
          end
          T=table;
          T.Variables={'Fecha'; 'Prof';'Temp'};
          T.Inferior={datestr(app.Profundidad.XAxis.Limits(1),'dd-mm-yyyy HH:MM:SS'); ...
            app.Profundidad.YAxis.Limits(1); app.Temperatura.YAxis.Limits(1)};
          T.superior={datestr(app.Profundidad.XAxis.Limits(2),'dd-mm-yyyy HH:MM:SS'); ...
            app.Profundidad.YAxis.Limits(2); app.Temperatura.YAxis.Limits(2)};
          app.Rangos.Data=T;
          app.mensaje('Archivo Cargado');
        else
          app.mensaje('Archivo NC no tiene la estructura de datos esperada!!!');
        end
      end
    end

    % Value changed function: FechaInicio
    function FechaInicioValueChanged(app, event)
      value = app.FechaInicio.Value;
      nc=app.Archivo.Value;
      Fecha=ncread(nc,'/Sensores/Fecha');
      Sel=logical(ncread(nc,'/Sensores/Sel'));
      Sel(Fecha<datenum(value,'dd-mm-yyyy HH:MM:SS'))=false;
      value=app.FechaTermino.Value;
      Sel(Fecha>datenum(value,'dd-mm-yyyy HH:MM:SS'))=false;
      Fecha=Fecha(Sel);
      I=ncinfo(nc,'/Sensores/');
      Var={I.Variables.Name};
      jj=contains(Var,{'Depth' 'Prof'});
      if sum(jj)>1
        j1=strcmpi(Var,'Depth');
        j2=strcmpi(Var,'Prof');
        if isequal(sum(j1),1) && isequal(sum(j2),1)
          jj=j2;
        elseif isequal(j1,1)
          jj=j1;
        else
          jj=j2;
        end
      end
      Prof=ncread(nc,['/Sensores/' Var{jj}]);
      Prof=Prof(Sel);
      jj=contains(Var,{'Temperature' 'Temperatura'});
      Temp=ncread(nc,['/Sensores/' Var{jj}]);
      Temp=Temp(Sel);
      plot(app.Profundidad,Fecha,Prof);
      datetick(app.Profundidad)
      plot(app.Temperatura,Fecha,Temp);
      datetick(app.Temperatura)
      T=table;
      T.Variables={'Fecha'; 'Prof';'Temp'};
      T.Inferior={datestr(app.Profundidad.XAxis.Limits(1),'dd-mm-yyyy HH:MM:SS'); ...
        app.Profundidad.YAxis.Limits(1); app.Temperatura.YAxis.Limits(1)};
      T.superior={datestr(app.Profundidad.XAxis.Limits(2),'dd-mm-yyyy HH:MM:SS'); ...
        app.Profundidad.YAxis.Limits(2); app.Temperatura.YAxis.Limits(2)};
      app.Rangos.Data=T;
      
    end

    % Value changed function: FechaTermino
    function FechaTerminoValueChanged(app, event)
      value = app.FechaTermino.Value;
      nc=app.Archivo.Value;
      Fecha=ncread(nc,'/Sensores/Fecha');
      Sel=logical(ncread(nc,'/Sensores/Sel'));
      Sel(Fecha>datenum(value,'dd-mm-yyyy HH:MM:SS'))=false;
      value=app.FechaInicio.Value;
      Sel(Fecha<datenum(value,'dd-mm-yyyy HH:MM:SS'))=false;
      Fecha=Fecha(Sel);
      I=ncinfo(nc,'/Sensores/');
      Var={I.Variables.Name};
      jj=contains(Var,{'Depth' 'Prof'});
      if sum(jj)>1
        j1=strcmpi(Var,'Depth');
        j2=strcmpi(Var,'Prof');
        if isequal(sum(j1),1) && isequal(sum(j2),1)
          jj=j2;
        elseif isequal(j1,1)
          jj=j1;
        else
          jj=j2;
        end
      end
      Prof=ncread(nc,['/Sensores/' Var{jj}]);
      Prof=Prof(Sel);
      jj=contains(Var,{'Temperature' 'Temperatura'});
      Temp=ncread(nc,['/Sensores/' Var{jj}]);
      Temp=Temp(Sel);
      plot(app.Profundidad,Fecha,Prof);
      datetick(app.Profundidad)
      plot(app.Temperatura,Fecha,Temp);
      datetick(app.Temperatura)
      T=table;
      T.Variables={'Fecha'; 'Prof';'Temp'};
      T.Inferior={datestr(app.Profundidad.XAxis.Limits(1),'dd-mm-yyyy HH:MM:SS'); ...
        app.Profundidad.YAxis.Limits(1); app.Temperatura.YAxis.Limits(1)};
      T.superior={datestr(app.Profundidad.XAxis.Limits(2),'dd-mm-yyyy HH:MM:SS'); ...
        app.Profundidad.YAxis.Limits(2); app.Temperatura.YAxis.Limits(2)};
      app.Rangos.Data=T;
      
    end

    % Button pushed function: ProcesarButton
    function ProcesarButtonPushed(app, event)
      clc
      app.Mensajes.Items={''};app.Mensajes.ItemsData=1;
      app.np=0;
      app.mensaje('Iniciando Procesamiento');
      HUSO=str2double(erase(app.HusoHorario.Value,'UTC'));
      if isnan(HUSO)
        HUSO=0;
      end
      Huso=str2double(erase(app.Usar.Value,'UTC'));
      if isnan(Huso)
        Huso=0;
      end
      Modifica=-HUSO/24+Huso/24;
      Fini= datenum(app.FechaInicio.Value,'dd-mm-yyyy HH:MM:SS')+Modifica;
      Fend= datenum(app.FechaTermino.Value,'dd-mm-yyyy HH:MM:SS')+Modifica;
      nc=app.Archivo.Value;
      Instalado=ncreadatt(nc,'/Configuracion/','Instalado');
      Fondeo_Superficie=~contains(Instalado,{'Fondo' 'Media Agua'});
      
      [cam,arc,~]=fileparts(nc);
      if app.ConfiguracinCheckBox.Value
        I=ncinfo(nc,'/Configuracion/');
        Var={I.Variables.Name};
        if any(contains(Var,'Latitud')) && any(contains(Var,'Longitud'))
          Lat=ncread(nc,'/Configuracion/Latitud');
          Lon=ncread(nc,'/Configuracion/Longitud');
          RES.Este=ncread(nc,'/Configuracion/Este');
          RES.Norte=ncread(nc,'/Configuracion/Norte');
          if ~isnan(Lat) && ~isnan(Lon)
            iconStr='http://maps.google.com/mapfiles/kml/shapes/shaded_dot.png';
            archivo=fullfile(cam,'Posicion ADCP.kml');
            kmlwritepoint(archivo,Lat,Lon,'Name','ADCP','Icon',iconStr);
          end
          app.mensaje('Archivo kml creado');
        else
          [Lat,Lon]=deal(NaN);
        end
      end
      
      if app.SensoresCheckBox.Value
        %
        % Series de Tiempo
        %
        txt='Procesando datos de los Sensores';
        app.mensaje(txt);
        I=ncinfo(nc,'/Sensores');
        Var={I.Variables.Name};
        Fecha=ncread(nc,'/Sensores/Fecha')+Modifica;
        Sel=logical(ncread(nc,'/Sensores/Sel'));
        Sel(Fecha<Fini)=false;
        Sel(Fecha>Fend)=false;
        T=table;
        T.Fecha=exceltime(datetime(datevec(Fecha(Sel))));
        for jv=1:length(Var)
          if ~contains(Var{jv},{'Fecha' 'Sel'})
            v=ncread(nc,['/Sensores/' Var{jv}]);
            T.(Var{jv})=v(Sel);
          end
        end
        xls=fullfile(cam,[arc ' Series.xlsx']);
        hoja='Series';
        if exist(xls,'file')
          delete(xls)
        end
        writetable(T,xls,'Sheet',hoja,'Range','A1');
        xls_delete_sheets(xls);
        app.np=app.np-1;
        app.mensaje([txt ' Listo!!'])
      end
      if app.ColumnaCheckBox.Value
        app.mensaje('Procesando datos de la Columna');
        I=ncinfo(nc,'/Sensores/');
        Var={I.Variables.Name};
        jj=contains(Var,{'Depth' 'Prof'});
        if sum(jj)>0
          j1=strcmpi(Var,'Depth');
          j2=strcmpi(Var,'Prof');
          if isequal(sum(j1),1) && isequal(sum(j2),1)
            jj=j2;
          elseif isequal(sum(j1),1)
            jj=j1;
          else
            jj=j2;
          end
        end
        Prof=ncread(nc,['/Sensores/' Var{jj}]);
        RES.Prof=Prof;
        I=ncinfo(nc,'/Columna/');
        Var={I.Variables.Name}';
        Fecha=ncread(nc,'/Columna/Fecha')+Modifica;
        NF=length(Fecha);
        Sel=ncread(nc,'/Columna/Sel');
        Sel(isnan(Sel))=0;
        Sel=logical(Sel);
        Sel(Fecha<Fini)=false;
        Sel(Fecha>Fend)=false;
        RES.Fecha=Fecha;
        RES.Sel=Sel;
        for jv=1:length(Var)
          switch Var{jv}
            case {'East_Speed' 'U'}
              U=ncread(nc,['/Columna/' Var{jv}]);
            case {'North_Speed' 'V'}
              V=ncread(nc,['/Columna/' Var{jv}]);
            case {'Horizontal_Speed' 'Mag'}
              Mag=ncread(nc,['/Columna/' Var{jv}]);
            case {'Direction' 'Dir'}
              Dir=ncread(nc,['/Columna/' Var{jv}]);
            case {'Capas'}
              RES.Capas=ncread(nc,['/Columna/' Var{jv}]);
            case {'NCapas'}
              NCapas=ncread(nc,['/Columna/' Var{jv}]);
            case {'EspesorCapa'}
              EspesorCapa=ncread(nc,['/Columna/' Var{jv}]);
          end
        end
        
        mask=ones(NF,NCapas);
        for j=1:NF
          if Sel(j)
            if ~Fondeo_Superficie
              jp=RES.Capas>Prof(j);
              mask(j,jp)=0;
            end
          else
            mask(j,:)=0;
          end
        end
        dt=((Fecha(2)-Fecha(1))*24*60)*60; % tiempo en segundos
        %
        % Correcion Magnetica
        %
        app.mensaje('Aplicando Correccon Magnetica')
        CorMag=zeros(NF,1);
        if ~isnan(Lat)
          aa=2000:5:2015;
          for j=1:NF
            jj=find(year(Fecha(j))>=aa);
            if ~isempty(jj)
              if isequal(aa(jj(end)),2015)
                [~, ~, CorMag(j), ~, ~] = wrldmagm(0,Lat,Lon,decyear(datevec(Fecha(j))));
              else
                [~,~, CorMag(j),~,~,~,~,~,~,~]=igrf11magm(0,Lat,Lon,decyear(datevec(Fecha(j))));
              end
            end
          end
        end
        for j=1:NCapas
          RES.Dir(:,j)=Dir(:,j)+CorMag;
        end
        jj=RES.Dir>=360;
        RES.Dir(jj)=RES.Dir(jj)-360;
        jj=RES.Dir<0;
        RES.Dir(jj)=360+RES.Dir(jj);
        RES.Mag=Mag;
        [RES.U,RES.V]=polar2uv(RES.Dir,RES.Mag);
        [x,y]=pvd(dt,RES.U(Sel,:).*mask(Sel,:),RES.V(Sel,:).*mask(Sel,:));% Distancia en m
        [d2,spd]=uv2polar(nanmean(RES.U(Sel,:))',nanmean(RES.V(Sel,:))');
        d=NaN(NCapas,1);
        for j=1:NCapas
          k=find(mask(Sel,j),1,'last');
          if ~isempty(k)
            d(j)=sqrt(x(k,j)^2+y(k,j)^2);
          end
        end
        jc=d==0 | isnan(d);
        MC=ones(size(d));
        [MC(jc),spd(jc),d2(jc),x(:,jc),y(:,jc)]=deal(NaN);
        RES.ssd=sdeg(d2);
        RES.nc=length(find(~isnan(MC)));
        RES.DT=false;
        app.mensaje('Definiendo Capas');
        if (Fend-Fini)>5 && ~isnan(Lat) && ~Fondeo_Superficie
          if isequal(nanstd(Prof(Sel)),0)
            RES.NRS=nanmax(abs(Prof(Sel)));
          else
            coef=ut_solv(Fecha(Sel),Prof(Sel),[],Lat,'auto');
            nmm=nanmean(Prof(Sel));
            m2=coef.A(strcmp(coef.name,'M2'),1);
            s2=coef.A(strcmp(coef.name,'S2'),1);
            n2=coef.A(strcmp(coef.name,'N2'),1);
            k1=coef.A(strcmp(coef.name,'K1'),1);
            o1=coef.A(strcmp(coef.name,'O1'),1);
            RES.NRS=nmm-nansum([m2 s2 n2 k1 o1]);
          end
          RES.DT=true;
        else
          if ~Fondeo_Superficie
            RES.NRS=nanmin(Prof(Sel));
          else
            RES.NRS=nanmax(abs(Prof(Sel)));
          end
          if Fend-Fini>5
            RES.DT=true;
          end
        end
        RES.capainfo=NaN(NCapas,7);
        RES.capainfo(:,1)=(1:NCapas)'.*MC;
        RES.capainfo(:,2)=RES.Capas.*MC;
        RES.capainfo(:,3)=(RES.Capas-EspesorCapa/2).*MC;
        RES.capainfo(:,4)=(RES.Capas+EspesorCapa/2).*MC;
        if ~Fondeo_Superficie
          RES.capainfo(:,5)=(RES.NRS-RES.Capas).*MC;
        else
          RES.capainfo(:,5)=(RES.NRS+RES.Capas).*MC;
        end
        RES.capainfo(:,6)=(RES.capainfo(:,5)-EspesorCapa/2).*MC;
        RES.capainfo(:,7)=(RES.capainfo(:,5)+EspesorCapa/2).*MC;
        %define capa Fondo Intermedia y Superior
        RES.neto=NaN(RES.nc,5);
        RES.neto(:,1)=RES.capainfo(1:RES.nc,1);
        RES.neto(:,2)=RES.capainfo(1:RES.nc,5);
        if ~Fondeo_Superficie
          RES.Fon=1;
          jj=find(RES.neto(:,2)>0);
          if RES.capainfo(jj(end),6)>0
            RES.Sup=jj(end-1);
          else
            RES.Sup=jj(end-2);
          end
          RES.Int=fix(RES.Sup/2);
        else
          RES.Sup=1;
          RES.Fon=NCapas;
          if mod(RES.Fon,2)>0
            RES.Int=fix(RES.Fon/2)+1;
          else
            RES.Int=fix(RES.Fon/2);
          end
        end
        o1=RES.Fon;
        o2=RES.Sup;
        if RES.Sup<RES.Fon
          o1=RES.Sup;
          o2=RES.Fon;
        end
        RES.x=x(:,o1:o2)/1000;
        RES.y=y(:,o1:o2)/1000;
        app.mensaje('Obteniendo Estadisticas');
        RES.estmag=NaN(o2,10);
        RES.estmag(:,1)=RES.capainfo(o1:o2,1);
        RES.estmag(:,2)=RES.capainfo(o1:o2,5);
        RES.estmag(:,3)=sum(~isnan(RES.U(Sel,o1:o2)))';
        RES.estmag(:,4)=nanmin(RES.Mag(Sel,o1:o2)); %minimo
        RES.estmag(:,5)=nanmean(RES.Mag(Sel,o1:o2))'; %promedio
        RES.estmag(:,6)=nanmax(RES.Mag(Sel,o1:o2))'; %maximo
        RES.estmag(:,7)=nanstd(RES.Mag(Sel,o1:o2))'; %des std
        RES.estmag(:,8)=RES.estmag(:,7)./RES.estmag(:,5).*100; %coef variacion
        RES.estmag(:,9)=convvel(RES.estmag(:,5),'m/s','kts'); %nudos
        RES.estmag(:,10)=convvel(RES.estmag(:,6),'m/s','kts');%nudos
        app.mensaje('Obteniendo Flujos');
        
        RES.neto(o1:o2,3)=d2(o1:o2);
        RES.neto(o1:o2,5)=d(o1:o2);
        RES.neto(o1:o2,4)=spd(o1:o2);
        app.mensaje('Obteniendo Frecuencias');
        
        RES.FrecDir=fdir(RES.Dir(Sel,o1:o2),45,1)*100;
        RES.FrecMag=fmag(RES.Mag(Sel,o1:o2)*100)*100; %calculo asume mag en cm/s
        %espectros
        if RES.DT
          app.mensaje('Obteniendo Espectros');
          
          f=Fecha(Sel);
          u=RES.U(Sel,o1:o2);
          v=RES.V(Sel,o1:o2);
          
          fv=datevec(f);
          jh=fv(:,5)==0;
          if sum(jh)>0
            u=u(jh,:);
            v=v(jh,:);
          else
            f1=datenum([fv(1,1:4) 0 0]);
            if f1<f(1)
              f1=f1+1/24;
            end
            f2=datenum([fv(end,1:4) 0 0]);
            if f2>f(end)
              f2=f2-1/24;
            end
            fe=f1:1/24:f2;
            u=interp1(f,u,fe);
            v=interp1(f,v,fe);
          end
          gl=12; %Grados de libertad
          for j=o1:o2
            [RES.P{j},RES.k{j},RES.m{j},RES.ncc{j},RES.nce{j}]= espec(u(:,j),...
              v(:,j),gl);
            RES.pmax(j,1)=fix(log10(max(max(RES.P{j}(:,2:3)))))+1;
          end
        end
        app.mensaje('Guardando Resultados');
        guarda_resultados(app,RES)
        app.mensaje('Generando Graficos');
        gen_grapher(app,RES)
        app.mensaje('Procesamiento Terminado');
        
      end
    end

    % Close request function: ProceaDatosADCPUIFigure
    function ProceaDatosADCPUIFigureCloseRequest(app, event)
      delete(app)
      
    end

    % Button pushed function: CerrarButton
    function CerrarButtonPushed(app, event)
      clc
      ProceaDatosADCPUIFigureCloseRequest(app, event)
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.ProceaDatosADCPUIFigure = uifigure('Visible', 'off');
      app.ProceaDatosADCPUIFigure.Name = 'Procea Datos ADCP';
      app.ProceaDatosADCPUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProceaDatosADCPUIFigureCloseRequest, true);
      app.ArchivoButton = uibutton(app.ProceaDatosADCPUIFigure, 'push');
      app.ArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @ArchivoButtonPushed, true);
      app.ArchivoButton.Text = 'Archivo';
      app.Profundidad = uiaxes(app.ProceaDatosADCPUIFigure);
      ylabel(app.Profundidad, 'Profundidad')
      app.Archivo = uieditfield(app.ProceaDatosADCPUIFigure, 'text');
      app.Archivo.Editable = 'off';
      app.Temperatura = uiaxes(app.ProceaDatosADCPUIFigure);
      ylabel(app.Temperatura, 'Temperatura')
      app.FechaInicioDropDownLabel = uilabel(app.ProceaDatosADCPUIFigure);
      app.FechaInicioDropDownLabel.Text = 'Fecha Inicio';
      app.FechaInicio = uidropdown(app.ProceaDatosADCPUIFigure);
      app.FechaInicio.Items = {''};
      app.FechaInicio.ValueChangedFcn = createCallbackFcn(app, @FechaInicioValueChanged, true);
      app.FechaInicio.Value = '';
      app.FechaTerminoLabel = uilabel(app.ProceaDatosADCPUIFigure);
      app.FechaTerminoLabel.Text = 'Fecha Termino';
      app.FechaTermino = uidropdown(app.ProceaDatosADCPUIFigure);
      app.FechaTermino.Items = {''};
      app.FechaTermino.ValueChangedFcn = createCallbackFcn(app, @FechaTerminoValueChanged, true);
      app.FechaTermino.Value = '';
      app.Rangos = uitable(app.ProceaDatosADCPUIFigure);
      app.Rangos.ColumnName = {'Variable'; 'Inferior'; 'Superior'};
      app.Rangos.RowName = {};
      app.LimitesEjesLabel = uilabel(app.ProceaDatosADCPUIFigure);
      app.LimitesEjesLabel.Text = 'Limites Ejes';
      app.ProcesarButton = uibutton(app.ProceaDatosADCPUIFigure, 'push');
      app.ProcesarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarButtonPushed, true);
      app.ProcesarButton.Text = 'Procesar';
      app.InformacinDisponiblePanel = uipanel(app.ProceaDatosADCPUIFigure);
      app.InformacinDisponiblePanel.Title = 'Información Disponible';
      app.ConfiguracinCheckBox = uicheckbox(app.InformacinDisponiblePanel);
      app.ConfiguracinCheckBox.Text = 'Configuración';
      app.SensoresCheckBox = uicheckbox(app.InformacinDisponiblePanel);
      app.SensoresCheckBox.Text = 'Sensores';
      app.ColumnaCheckBox = uicheckbox(app.InformacinDisponiblePanel);
      app.ColumnaCheckBox.Text = 'Columna';
      app.SuperficieCheckBox = uicheckbox(app.InformacinDisponiblePanel);
      app.SuperficieCheckBox.Text = 'Superficie';
      app.Columna2CheckBox = uicheckbox(app.InformacinDisponiblePanel);
      app.Columna2CheckBox.Text = 'Columna 2';
      app.Columna3CheckBox = uicheckbox(app.InformacinDisponiblePanel);
      app.Columna3CheckBox.Text = 'Columna 3';
      app.OleajeCheckBox = uicheckbox(app.InformacinDisponiblePanel);
      app.OleajeCheckBox.Text = 'Oleaje';
      app.NavegancinCheckBox = uicheckbox(app.InformacinDisponiblePanel);
      app.NavegancinCheckBox.Text = 'Naveganción';
      app.Mensajes = uilistbox(app.ProceaDatosADCPUIFigure);
      app.Mensajes.Items = {''};
      app.Mensajes.Value = '';
      app.HusoHorarioArchivoDropDownLabel = uilabel(app.ProceaDatosADCPUIFigure);
      app.HusoHorarioArchivoDropDownLabel.Text = 'Huso Horario Archivo';
      app.HusoHorario = uidropdown(app.ProceaDatosADCPUIFigure);
      app.HusoHorario.Items = {'Sin', 'UTC', 'UTC-3', 'UTC-4'};
      app.HusoHorario.Value = 'Sin';
      app.UsarDropDownLabel = uilabel(app.ProceaDatosADCPUIFigure);
      app.UsarDropDownLabel.Text = 'Usar';
      app.Usar = uidropdown(app.ProceaDatosADCPUIFigure);
      app.Usar.Items = {'Sin', 'UTC', 'UTC-3', 'UTC-4'};
      app.Usar.Value = 'Sin';
      app.CerrarButton = uibutton(app.ProceaDatosADCPUIFigure, 'push');
      app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
      app.CerrarButton.Text = 'Cerrar';
      % Show the figure after all components are created
      app.ProceaDatosADCPUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = ADCP_Procesa

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.ProceaDatosADCPUIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.ProceaDatosADCPUIFigure)
    end
  end
end